classdef GenTH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        USRAUncertaintySiteResponseAnalysisUIFigure  matlab.ui.Figure
        OkButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        TimeHistoryGenerationLabel     matlab.ui.control.Label
        GenerationDataInputPanel       matlab.ui.container.Panel
        NumberofsamplesSpinnerLabel    matlab.ui.control.Label
        NumberofsamplesSpinner         matlab.ui.control.Spinner
        timestepsEditFieldLabel        matlab.ui.control.Label
        timestepsEditField             matlab.ui.control.NumericEditField
        durationsEditFieldLabel        matlab.ui.control.Label
        durationsEditField             matlab.ui.control.NumericEditField
        StationarywindowsEditFieldLabel  matlab.ui.control.Label
        StationarywindowsEditField     matlab.ui.control.NumericEditField
        StochasticPSDButtonGroup       matlab.ui.container.ButtonGroup
        StationaryButton               matlab.ui.control.RadioButton
        QuasistationaryButton          matlab.ui.control.RadioButton
        SpectrumcompatibleButton       matlab.ui.control.RadioButton
        Image                          matlab.ui.control.Image
        ExportButton                   matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        GenerateButton                 matlab.ui.control.Button
        ResponseSpectrumDropDownLabel  matlab.ui.control.Label
        ResponseSpectrumDropDown       matlab.ui.control.DropDown
        agms2EditFieldLabel            matlab.ui.control.Label
        agms2EditField                 matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ENRSA % European Response Spectrum
        T %natural period
        dt % time step
        Ts %stationary window
        tf %total duration
        nU %number of samples
        t %time vector 
        G0 %PSD
        wc %circular freq vector
        %
        CallingApp % Main app object
        THarray % matrix
        %
    end
    
    methods (Access = private)
        
        function [T,RSA] = UpdateSpectrum(app,EN,T)
            switch EN
                case 'EN1998 GrType A'
                    soiltype='A';
                case 'EN1998 GrType B'
                    soiltype='B';
                case 'EN1998 GrType C'
                    soiltype='C';
                case 'EN1998 GrType D'
                    soiltype='D';
                otherwise
                    soiltype='A';
            end
            ag=app.agms2EditField.Value;
            [RSA,T]=EN8RSA(ag,soiltype,T);
            app.T=T;
            app.ENRSA=RSA;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, PSD)
               % Store main app object
               app.CallingApp = mainapp;
            if isempty(PSD)
                app.QuasistationaryButton.Enable='off';
                app.StationaryButton.Enable='off';
            else
                app.QuasistationaryButton.Enable='on';
                app.StationaryButton.Enable='on';
                app.G0=PSD(:,2);
                app.wc=PSD(:,1);
            end
            app.THarray=[];
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.dt=app.timestepsEditField.Value;
            app.Ts=app.StationarywindowsEditField.Value;
            app.nU=app.NumberofsamplesSpinner.Value;
            app.tf=app.durationsEditField.Value;
            app.t=0:app.dt:app.tf;
            ww=0:2*pi/app.tf:1/2/app.dt*2*pi;
            if app.SpectrumcompatibleButton.Value==1
                EN=app.ResponseSpectrumDropDown.Value;
                app.T=2*pi./ww;
                [~,RSA] = UpdateSpectrum(app,EN,app.T);
                app.G0=SpCompPSD(RSA,ww,app.Ts);
                [ phi ] =modulatingf(app.t,app.Ts);
                
            elseif app.StationaryButton.Value==1
                 phi=ones(1,length(app.t));
                 PSD=interp1(app.wc,app.G0,ww,"nearest","extrap");
                 app.G0=PSD;
                 app.wc=ww;
            elseif app.QuasistationaryButton.Value==1
                [ phi ] =modulatingf(app.t,app.Ts);
            else
                %do nothing
            end
            oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
            set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch');
            drawnow;
            [ACC,~] = QSTHgeneration(app.nU,app.t,ww,app.G0,phi);
            plot(app.UIAxes,ACC(:,1),ACC(:,2:end));
            app.THarray=ACC;
            set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
        if ~isempty(app.THarray)
            [file,path,indx] = uiputfile({'*.mat','MAT-Files (*.mat)';
   '*.txt','Text File (*.txt)';
   '*.csv','Text File (*.csv)';
   '*.xlsx','Worksheets (*.xlsx)'}, ...
   'Select a File');
             filename = fullfile(path,file);
             switch indx
                case 1
                    save(filename,app.THarray,'-mat');
                case 2
                    for hh=1:nn
                        namef=fullfile(path,strcat(file(1:end-4),num2str(hh),'.txt'));
                        fileID = fopen(namef,'w');
                        fprintf(fileID,'%6s %12s\r\n','t','acc');
                        fprintf(fileID,'%6.4f %12.8f\r\n',[app.THarray(:,1); app.THarray(:,hh+1)]);
                        fclose(fileID);
                    end
                case 3
                        writematrix(app.THarray,filename,'FileType','text');
                case 4
                        writematrix(app.THarray,filename,'FileType','spreadsheet');        
                otherwise
                    %tdata=table(0,0,0,0);
             end    
       else
             fig = uifigure;
             uialert(fig,'Generate Time History Samples before exporting','No Data to Export','Icon','warning');
        end  
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.THarray=[];
            plot(app.UIAxes,0,0);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
                marray=app.THarray;
                UpdateTH(app.CallingApp,marray);
                marray=[app.wc' app.G0'];
                UpdatePSD(app.CallingApp,marray);
            % Delete the dialog box  
            delete(app)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.USRAUncertaintySiteResponseAnalysisUIFigure = uifigure('Visible', 'off');
            app.USRAUncertaintySiteResponseAnalysisUIFigure.Name = 'USRA - Uncertainty Site Response Analysis';
            app.USRAUncertaintySiteResponseAnalysisUIFigure.Icon = 'USRA-Logo2.png';
            app.OkButton = uibutton(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TimeHistoryGenerationLabel = uilabel(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.TimeHistoryGenerationLabel.Text = 'Time History Generation';
            app.GenerationDataInputPanel = uipanel(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.GenerationDataInputPanel.Title = 'Generation Data Input';
            app.NumberofsamplesSpinnerLabel = uilabel(app.GenerationDataInputPanel);
            app.NumberofsamplesSpinnerLabel.Text = 'Number of samples';
            app.NumberofsamplesSpinner = uispinner(app.GenerationDataInputPanel);
            app.NumberofsamplesSpinner.Limits = [1 Inf];
            app.NumberofsamplesSpinner.Value = 1;
            app.timestepsEditFieldLabel = uilabel(app.GenerationDataInputPanel);
            app.timestepsEditFieldLabel.Text = 'time step [s]';
            app.timestepsEditField = uieditfield(app.GenerationDataInputPanel, 'numeric');
            app.timestepsEditField.Limits = [0 Inf];
            app.timestepsEditField.Value = 0.01;
            app.durationsEditFieldLabel = uilabel(app.GenerationDataInputPanel);
            app.durationsEditFieldLabel.Text = 'duration [s]';
            app.durationsEditField = uieditfield(app.GenerationDataInputPanel, 'numeric');
            app.durationsEditField.Limits = [0 Inf];
            app.durationsEditField.Value = 30;
            app.StationarywindowsEditFieldLabel = uilabel(app.GenerationDataInputPanel);
            app.StationarywindowsEditFieldLabel.Text = 'Stationary window [s]';
            app.StationarywindowsEditField = uieditfield(app.GenerationDataInputPanel, 'numeric');
            app.StationarywindowsEditField.Limits = [0 Inf];
            app.StationarywindowsEditField.Value = 20;
            app.StochasticPSDButtonGroup = uibuttongroup(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.StochasticPSDButtonGroup.Title = 'Stochastic PSD';
            app.StationaryButton = uiradiobutton(app.StochasticPSDButtonGroup);
            app.StationaryButton.Text = 'Stationary';
            app.StationaryButton.Value = true;
            app.QuasistationaryButton = uiradiobutton(app.StochasticPSDButtonGroup);
            app.QuasistationaryButton.Text = 'Quasi-stationary';
            app.SpectrumcompatibleButton = uiradiobutton(app.StochasticPSDButtonGroup);
            app.SpectrumcompatibleButton.Text = 'Spectrum-compatible';
            app.Image = uiimage(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.Image.ImageSource = 'USRA-Logo.jpg';
            app.ExportButton = uibutton(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.DeleteButton = uibutton(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.GenerateButton = uibutton(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ResponseSpectrumDropDownLabel = uilabel(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.ResponseSpectrumDropDownLabel.Text = 'Response Spectrum';
            app.ResponseSpectrumDropDown = uidropdown(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.ResponseSpectrumDropDown.Items = {'EN1998 GrType A', 'EN1998 GrType B', 'EN1998 GrType C', 'EN1998 GrType D'};
            app.ResponseSpectrumDropDown.Value = 'EN1998 GrType A';
            app.agms2EditFieldLabel = uilabel(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.agms2EditFieldLabel.Text = 'ag [ms-2]';
            app.agms2EditField = uieditfield(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'numeric');
            app.agms2EditField.Limits = [0 Inf];
            app.agms2EditField.Value = 2;
            app.UIAxes = uiaxes(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            title(app.UIAxes, 'TH')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'acc [ms-2]')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.USRAUncertaintySiteResponseAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenTH(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.USRAUncertaintySiteResponseAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.USRAUncertaintySiteResponseAnalysisUIFigure)
        end
    end
end