classdef NLcurves < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VariationofCyclicParametersLabel  matlab.ui.control.Label
        OkButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        CurvesButtonGroup              matlab.ui.container.ButtonGroup
        ClayEERASeedandSun1989Idriss1990Button  matlab.ui.control.ToggleButton
        SandVuceticDobry1991IP0Button  matlab.ui.control.ToggleButton
        SandEERASeedIdriss1970Button   matlab.ui.control.ToggleButton
        ExperimentalDataButton         matlab.ui.control.ToggleButton
        AssigntoButton                 matlab.ui.control.Button
        MaterialIndexSpinnerLabel      matlab.ui.control.Label
        MaterialIndexSpinner           matlab.ui.control.Spinner
        ViewButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app object
        material % structure array
    end
    
    methods (Access = private)
        
        function updateFigure(app,matI)
            t = app.material(matI);
            % Plot modified data 
            yyaxis(app.UIAxes,'left')
            loglog(app.UIAxes,t.Xg,t.Yg,'LineWidth',2,'Color','k');
            app.UIAxes.YLabel.String='G/Gmax';
            app.UIAxes.YColor = 'k';
            hold(app.UIAxes,'on');
            yyaxis(app.UIAxes,'right')
            loglog(app.UIAxes,t.Xcsi,t.Ycsi,'LineWidth',2,'Color','r');
            app.UIAxes.YLabel.String='Damping [%]';
            app.UIAxes.YColor = 'r';
            hold(app.UIAxes,'off');
        end
        
        function [Xg,Yg,Xcsi,Ycsi] = ImportData(app)
            [file,path,indx] = uigetfile({'*.mat','MAT-Files (*.mat)';
   '*.txt;*.rtf;*.odt;*.csv','Worpad Documents (*.txt,*.rtf,*.odt,*.csv)';
            '.xls;*.xlsx','Worksheets (.xls,*.xlsx)';
   '*.*',  'All Files (*.*)'}, ...
   'Select a File');
            switch indx
                case 1
                    tdata=load(strcat(path,file));
                case 2
                    tdata=readtable(strcat(path,file),'Format','%f%f%f%u');
                case 3
                    tdata=readtable(strcat(path,file),'Format','auto');
                case 4
                    tdata=readtable(strcat(path,file),'Format','auto');
                otherwise
                    tdata=table(0,0,0,0);
            end
            %create table
            varNames = {'Xg','Yg','Xcsi','Ycsi'};
            tdata.Properties.VariableNames=varNames;
            % Add data to the Table UI Component
            Xg=tdata.Xg.';
            Xg(isnan(Xg))=[];
            Yg=tdata.Yg.';
            Yg(isnan(Yg))=[];
            if size(Xg) ~= size(Yg)
                fig = uifigure;
                uialert(fig,'G/Gmax curve not valid','Invalid number of elements');
                Xg=0;
                Yg=0;
            end
            Xcsi=tdata.Xcsi.';
            Xcsi(isnan(Xcsi))=[];
            Ycsi=tdata.Ycsi.';
            Ycsi(isnan(Ycsi))=[];
            if size(Xcsi) ~= size(Ycsi)
                fig = uifigure;
                uialert(fig,'Damping curve not valid','Invalid number of elements');
                Xcsi=0;
                Ycsi=0;
            end     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, marray)
                % Store main app object
               app.CallingApp = mainapp;
               %store material array
               app.material=marray;
        end

        % Button pushed function: AssigntoButton
        function AssigntoButtonPushed(app, event)
            switch app.CurvesButtonGroup.SelectedObject.Text
                case 'Sand (EERA-Seed & Idriss 1970)'
                        Xg = [0.0001 0.0003 0.001 0.003 0.01 0.03 0.1 0.3 1 3 10 100];
                        Yg = [1 1 1 0.981 0.941 0.847 0.656 0.438 0.238 0.144 0.11 0.11];
                        Xcsi = [0.0001 0.0003 0.001 0.003 0.01 0.03 0.1 0.3 1 3.16 10 100];
                        Ycsi = [0.24 0.42 0.8 1.4 2.8 5.1 9.8 15.5 21.0 25 28 28];
                case 'Sand (Vucetic/Dobry 1991, IP=0)'
                        Xg = [0.0001 0.0003 0.001 0.003 0.01 0.03 0.1 0.3 1 3 10];
                        Yg = [1 0.999 0.973 0.880 0.711 0.485 0.256 0.114 0.027 0.007 0.001];
                        Xcsi = [0.0001 0.0003 0.001 0.003 0.01 0.03 0.1 0.3 1 3 10];
                        Ycsi = [1.142 1.326 1.802 3.195 5.369 9.633 15.212 19.980 23.90 24 24];
                case 'Clay (EERA-Seed and Sun 1989, Idriss 1990)'
                    Xg = [0.0001 0.0003 0.001 0.003 0.01 0.03 0.1 0.3 1 3 10 100];
                    Yg = [1 1 0.99 0.96 0.85 0.64 0.37 0.18 0.08 0.05 0.035 0.035];
                    Xcsi = [0.0001 0.0003 0.001 0.003 0.01 0.03 0.1 0.3 1 3 10 100];
                    Ycsi = [0.24 0.42 0.8 1.4 2.8 5.1 9.8 15.5 21.0 25 28 28];
                case 'Experimental Data'
                    [Xg,Yg,Xcsi,Ycsi] = ImportData(app);
            end
            nmat=app.MaterialIndexSpinner.Value;
            app.material(nmat).Xg=Xg;
            app.material(nmat).Yg=Yg;
            app.material(nmat).Xcsi=Xcsi;
            app.material(nmat).Ycsi=Ycsi;
            updateFigure(app,nmat)
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            val=size(app.material,2);
            nmat=app.MaterialIndexSpinner.Value;
            if nmat > val
                fig = uifigure;
                uialert(fig,'Material Properties not found','Invalid Material Index');
            else
                updateFigure(app,nmat)
            end
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Delete the dialog box  
            delete(app)  
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Call main app's public function
            UpdateSoil(app.CallingApp,app.material);
            %create tmp file
            %save('CyclicProperties',app.material,'-mat');
            % Delete the dialog box  
            delete(app)    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VariationofCyclicParametersLabel = uilabel(app.UIFigure);
            app.VariationofCyclicParametersLabel.Text = 'Variation of Cyclic Parameters ';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CurvesButtonGroup = uibuttongroup(app.UIFigure);
            app.CurvesButtonGroup.Title = 'Curves';
            app.ClayEERASeedandSun1989Idriss1990Button = uitogglebutton(app.CurvesButtonGroup);
            app.ClayEERASeedandSun1989Idriss1990Button.Text = 'Clay (EERA-Seed and Sun 1989, Idriss 1990)';
            app.ClayEERASeedandSun1989Idriss1990Button.Value = true;
            app.SandVuceticDobry1991IP0Button = uitogglebutton(app.CurvesButtonGroup);
            app.SandVuceticDobry1991IP0Button.Text = 'Sand (Vucetic/Dobry 1991, IP=0)';
            app.SandEERASeedIdriss1970Button = uitogglebutton(app.CurvesButtonGroup);
            app.SandEERASeedIdriss1970Button.Text = 'Sand (EERA-Seed & Idriss 1970)';
            app.ExperimentalDataButton = uitogglebutton(app.CurvesButtonGroup);
            app.ExperimentalDataButton.Text = 'Experimental Data';
            app.AssigntoButton = uibutton(app.UIFigure, 'push');
            app.AssigntoButton.ButtonPushedFcn = createCallbackFcn(app, @AssigntoButtonPushed, true);
            app.AssigntoButton.Text = 'Assign to';
            app.MaterialIndexSpinnerLabel = uilabel(app.UIFigure);
            app.MaterialIndexSpinnerLabel.Text = 'Material Index';
            app.MaterialIndexSpinner = uispinner(app.UIFigure);
            app.MaterialIndexSpinner.Limits = [1 Inf];
            app.MaterialIndexSpinner.Value = 1;
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Shear deformation [%]')
            ylabel(app.UIAxes, 'Cyclic Properties')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLcurves(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end