classdef PSDinp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PowerSpectralDensityLabel       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        OkButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        CloughandPenzienPSDPanel        matlab.ui.container.Panel
        createButton                    matlab.ui.control.Button
        ParametersDropDownLabel         matlab.ui.control.Label
        ParametersDropDown              matlab.ui.control.DropDown
        wgEditFieldLabel                matlab.ui.control.Label
        wgEditField                     matlab.ui.control.NumericEditField
        zgEditFieldLabel                matlab.ui.control.Label
        zgEditField                     matlab.ui.control.NumericEditField
        zfEditFieldLabel                matlab.ui.control.Label
        zfEditField                     matlab.ui.control.NumericEditField
        wfEditFieldLabel                matlab.ui.control.Label
        wfEditField                     matlab.ui.control.NumericEditField
        SwEditFieldLabel                matlab.ui.control.Label
        SwEditField                     matlab.ui.control.NumericEditField
        SpectrumCompatiblePSDPanel      matlab.ui.container.Panel
        ResponseSpectrumDropDownLabel   matlab.ui.control.Label
        ResponseSpectrumDropDown        matlab.ui.control.DropDown
        agms2EditFieldLabel             matlab.ui.control.Label
        agms2EditField                  matlab.ui.control.NumericEditField
        createButton_2                  matlab.ui.control.Button
        StationarytimewindowsEditFieldLabel  matlab.ui.control.Label
        StationarytimewindowsEditField  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app object
        PSDinput % matrix 
    end
    
    
    methods (Access = private)
        
        function [T,RSA] = UpdateSpectrum(app,EN,T)
            switch EN
                case 'EN1998 GrType A'
                    soiltype='A';
                case 'EN1998 GrType B'
                    soiltype='B';
                case 'EN1998 GrType C'
                    soiltype='C';
                case 'EN1998 GrType D'
                    soiltype='D';
                otherwise
                    soiltype='A';
            end
            ag=app.agms2EditField.Value;
            [RSA,T]=EN8RSA(ag,soiltype,T);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, PSD)
                % Store main app object
               app.CallingApp = mainapp;
               %store material array
              if ~isempty(PSD)
                  app.PSDinput=PSD;
                   plot(app.UIAxes,PSD(:,1),PSD(:,2));
              end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Call main app's public function
            UpdatePSD(app.CallingApp,app.PSDinput);
            % Delete the dialog box  
            delete(app) 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            plot(app.UIAxes,0,0);
            app.PSDinput=[];
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path,indx] = uigetfile({'*.mat','MAT-Files (*.mat)';
   '*.txt;*.rtf;*.odt;*.csv','Worpad Documents (*.txt,*.rtf,*.odt,*.csv)';
   '*.*',  'All Files (*.*)'}, ...
   'Select a File');
            fname=fullfile(path,file);
            switch indx
                case 1
                    tdata=load(fname);
                case 2
                    tdata=readmatrix(fname);
                case 3
                    tdata=readmatrix(fname);
                otherwise
                    tdata=[];
            end
            app.PSDinput=tdata;
            plot(app.UIAxes,tdata(:,1),tdata(:,2));     
        end

        % Value changed function: ParametersDropDown
        function ParametersDropDownValueChanged(app, event)
            value = app.ParametersDropDown.Value;
            if value =='9'
                app.wfEditField.Editable=1;
                app.wgEditField.Editable=1;
                app.zfEditField.Editable=1;
                app.zgEditField.Editable=1;
            else
                [~,wg,zg,wf,zf]=PSDstatCP(1,str2double(value),1);
                app.wfEditField.Value=wf;
                app.wgEditField.Value=wg;
                app.zfEditField.Value=zf;
                app.zgEditField.Value=zg;
                app.wfEditField.Editable=0;
                app.wgEditField.Editable=0;
                app.zfEditField.Editable=0;
                app.zgEditField.Editable=0;
            end
        end

        % Button pushed function: createButton
        function createButtonPushed(app, event)
            value = app.ParametersDropDown.Value;
            w=0:0.01*2*pi:50*2*pi;
            Sw=app.SwEditField.Value;
            if value == '9'
                wf=app.wfEditField.Value;
                wg=app.wgEditField.Value;
                zf=app.zfEditField.Value;
                zg=app.zgEditField.Value;
                [PSD,~,~,~,~] = PSDstatCP(w,1,Sw,wg,zg,wf,zf);
            else
                [PSD,~,~,~,~] = PSDstatCP(w,str2double(value),Sw);
            end
            app.PSDinput=[w' PSD'];
            plot(app.UIAxes,w,PSD,'LineWidth',1,'Color','r');   
        end

        % Button pushed function: createButton_2
        function createButton_2Pushed(app, event)
                ww=0:0.01*2*pi:50*2*pi;
                EN=app.ResponseSpectrumDropDown.Value;
                T=2*pi./ww;
                [~,RSA] = UpdateSpectrum(app,EN,T);
                Ts=app.StationarytimewindowsEditField.Value;
                G0=SpCompPSD(RSA,ww,Ts);
                app.PSDinput=[ww' G0'];
                plot(app.UIAxes,ww,G0,'LineWidth',1,'Color','b');   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PowerSpectralDensityLabel = uilabel(app.UIFigure);
            app.PowerSpectralDensityLabel.Text = 'Power Spectral Density';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'USRA-Logo.jpg';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.CloughandPenzienPSDPanel = uipanel(app.UIFigure);
            app.CloughandPenzienPSDPanel.Title = 'Clough and Penzien PSD';
            app.createButton = uibutton(app.CloughandPenzienPSDPanel, 'push');
            app.createButton.ButtonPushedFcn = createCallbackFcn(app, @createButtonPushed, true);
            app.createButton.Text = 'create';
            app.ParametersDropDownLabel = uilabel(app.CloughandPenzienPSDPanel);
            app.ParametersDropDownLabel.Text = 'Parameters';
            app.ParametersDropDown = uidropdown(app.CloughandPenzienPSDPanel);
            app.ParametersDropDown.Items = {'Firm Soil', 'Medium soil', 'Soft soil', 'EC8 - Soil A', 'EC8 - Soil B', 'EC8 - Soil C', 'EC8 - Soil D', 'EC8 - Soil E', 'Manual'};
            app.ParametersDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ParametersDropDown.ValueChangedFcn = createCallbackFcn(app, @ParametersDropDownValueChanged, true);
            app.ParametersDropDown.Value = '1';
            app.wgEditFieldLabel = uilabel(app.CloughandPenzienPSDPanel);
            app.wgEditFieldLabel.Text = 'wg';
            app.wgEditField = uieditfield(app.CloughandPenzienPSDPanel, 'numeric');
            app.wgEditField.Limits = [0 Inf];
            app.wgEditField.Editable = 'off';
            app.wgEditField.Value = 15;
            app.zgEditFieldLabel = uilabel(app.CloughandPenzienPSDPanel);
            app.zgEditFieldLabel.Text = 'zg';
            app.zgEditField = uieditfield(app.CloughandPenzienPSDPanel, 'numeric');
            app.zgEditField.Limits = [0 Inf];
            app.zgEditField.Editable = 'off';
            app.zgEditField.Value = 0.6;
            app.zfEditFieldLabel = uilabel(app.CloughandPenzienPSDPanel);
            app.zfEditFieldLabel.Text = 'zf';
            app.zfEditField = uieditfield(app.CloughandPenzienPSDPanel, 'numeric');
            app.zfEditField.Limits = [0 Inf];
            app.zfEditField.Editable = 'off';
            app.zfEditField.Value = 0.6;
            app.wfEditFieldLabel = uilabel(app.CloughandPenzienPSDPanel);
            app.wfEditFieldLabel.Text = 'wf';
            app.wfEditField = uieditfield(app.CloughandPenzienPSDPanel, 'numeric');
            app.wfEditField.Limits = [0 Inf];
            app.wfEditField.Editable = 'off';
            app.wfEditField.Value = 1.5;
            app.SwEditFieldLabel = uilabel(app.CloughandPenzienPSDPanel);
            app.SwEditFieldLabel.Text = 'Sw';
            app.SwEditField = uieditfield(app.CloughandPenzienPSDPanel, 'numeric');
            app.SwEditField.Limits = [0 Inf];
            app.SwEditField.Value = 1;
            app.SpectrumCompatiblePSDPanel = uipanel(app.UIFigure);
            app.SpectrumCompatiblePSDPanel.Title = 'Spectrum-Compatible PSD';
            app.ResponseSpectrumDropDownLabel = uilabel(app.SpectrumCompatiblePSDPanel);
            app.ResponseSpectrumDropDownLabel.Text = 'Response Spectrum';
            app.ResponseSpectrumDropDown = uidropdown(app.SpectrumCompatiblePSDPanel);
            app.ResponseSpectrumDropDown.Items = {'EN1998 GrType A', 'EN1998 GrType B', 'EN1998 GrType C', 'EN1998 GrType D'};
            app.ResponseSpectrumDropDown.Value = 'EN1998 GrType A';
            app.agms2EditFieldLabel = uilabel(app.SpectrumCompatiblePSDPanel);
            app.agms2EditFieldLabel.Text = 'ag [ms-2]';
            app.agms2EditField = uieditfield(app.SpectrumCompatiblePSDPanel, 'numeric');
            app.agms2EditField.Limits = [0 Inf];
            app.agms2EditField.Value = 2;
            app.createButton_2 = uibutton(app.SpectrumCompatiblePSDPanel, 'push');
            app.createButton_2.ButtonPushedFcn = createCallbackFcn(app, @createButton_2Pushed, true);
            app.createButton_2.Text = 'create';
            app.StationarytimewindowsEditFieldLabel = uilabel(app.SpectrumCompatiblePSDPanel);
            app.StationarytimewindowsEditFieldLabel.Text = 'Stationary time window [s]';
            app.StationarytimewindowsEditField = uieditfield(app.SpectrumCompatiblePSDPanel, 'numeric');
            app.StationarytimewindowsEditField.Limits = [0 Inf];
            app.StationarytimewindowsEditField.Value = 20;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PSD')
            xlabel(app.UIAxes, 'Circular Frequency [rad/s]')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSDinp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end