classdef RVTgen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TimeHistoryGenerationLabel   matlab.ui.control.Label
        GenerationDataInputPanel     matlab.ui.container.Panel
        NumberofsamplesSpinnerLabel  matlab.ui.control.Label
        NumberofsamplesSpinner       matlab.ui.control.Spinner
        timestepsEditFieldLabel      matlab.ui.control.Label
        timestepsEditField           matlab.ui.control.NumericEditField
        durationsEditFieldLabel      matlab.ui.control.Label
        durationsEditField           matlab.ui.control.NumericEditField
        StationarywindowsEditFieldLabel  matlab.ui.control.Label
        StationarywindowsEditField   matlab.ui.control.NumericEditField
        Image                        matlab.ui.control.Image
        OkButton                     matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app object
        PSDstruct % struct
        %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mstruct)
            % Store main app object
            app.CallingApp = mainapp;
            app.PSDstruct=mstruct;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            PSD=app.PSDstruct.PSD;
            omega=app.PSDstruct.freq.*2.*pi;
            dt=app.timestepsEditField.Value;
            Ts=app.StationarywindowsEditField.Value;
            nU=app.NumberofsamplesSpinner.Value;
            tf=app.durationsEditField.Value;
            t=0:dt:tf;
            [ phi ] =modulatingf(t,Ts);
            oldpointer = get(app.UIFigure, 'pointer');
            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
            [ACC,~] = QSTHgeneration(nU,t,omega,PSD.',phi);
            set(app.UIFigure, 'pointer', oldpointer); 
            UpdateRVT(app.CallingApp,ACC);
            % Delete the dialog box  
            delete(app) 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            UpdatePSD(app.CallingApp,[]);
            % Delete the dialog box  
            delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeHistoryGenerationLabel = uilabel(app.UIFigure);
            app.TimeHistoryGenerationLabel.Text = 'Time History Generation';
            app.GenerationDataInputPanel = uipanel(app.UIFigure);
            app.GenerationDataInputPanel.Title = 'Generation Data Input';
            app.NumberofsamplesSpinnerLabel = uilabel(app.GenerationDataInputPanel);
            app.NumberofsamplesSpinnerLabel.Text = 'Number of samples';
            app.NumberofsamplesSpinner = uispinner(app.GenerationDataInputPanel);
            app.NumberofsamplesSpinner.Limits = [1 Inf];
            app.NumberofsamplesSpinner.Value = 30;
            app.timestepsEditFieldLabel = uilabel(app.GenerationDataInputPanel);
            app.timestepsEditFieldLabel.Text = 'time step [s]';
            app.timestepsEditField = uieditfield(app.GenerationDataInputPanel, 'numeric');
            app.timestepsEditField.Limits = [0 Inf];
            app.timestepsEditField.Value = 0.01;
            app.durationsEditFieldLabel = uilabel(app.GenerationDataInputPanel);
            app.durationsEditFieldLabel.Text = 'duration [s]';
            app.durationsEditField = uieditfield(app.GenerationDataInputPanel, 'numeric');
            app.durationsEditField.Limits = [0 Inf];
            app.durationsEditField.Value = 30;
            app.StationarywindowsEditFieldLabel = uilabel(app.GenerationDataInputPanel);
            app.StationarywindowsEditFieldLabel.Text = 'Stationary window [s]';
            app.StationarywindowsEditField = uieditfield(app.GenerationDataInputPanel, 'numeric');
            app.StationarywindowsEditField.Limits = [0 Inf];
            app.StationarywindowsEditField.Value = 20;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'USRA-Logo.jpg';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RVTgen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end