classdef SignalProc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SignalProcessingLabel         matlab.ui.control.Label
        FFTPanel                      matlab.ui.container.Panel
        NumberofPointsEditFieldLabel  matlab.ui.control.Label
        NumberofPointsEditField       matlab.ui.control.NumericEditField
        IntegrationandDifferentiationPanel  matlab.ui.container.Panel
        BaselineCorrectionandZeroPaddingPanel  matlab.ui.container.Panel
        PolynomialcorrectionSpinnerLabel  matlab.ui.control.Label
        PolynomialcorrectionSpinner   matlab.ui.control.Spinner
        ZeropaddingnumberofpointsEditFieldLabel  matlab.ui.control.Label
        ZeropaddingnumberofpointsEditField  matlab.ui.control.NumericEditField
        SetButton                     matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app object 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            np=app.ZeropaddingnumberofpointsEditField.Value;
            npp=app.PolynomialcorrectionSpinner.Value;
            values=struct('polin',round(np,0),'npoints',round(npp,0));
            %update main app
            UpdateSignal(app.CallingApp,values);
            % Delete the dialog box  
            delete(app)     
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             % Delete the dialog box  
            delete(app)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignalProcessingLabel = uilabel(app.UIFigure);
            app.SignalProcessingLabel.Text = 'Signal Processing';
            app.FFTPanel = uipanel(app.UIFigure);
            app.FFTPanel.Title = 'FFT';
            app.NumberofPointsEditFieldLabel = uilabel(app.FFTPanel);
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.NumberofPointsEditField = uieditfield(app.FFTPanel, 'numeric');
            app.NumberofPointsEditField.Limits = [0 Inf];
            app.IntegrationandDifferentiationPanel = uipanel(app.UIFigure);
            app.IntegrationandDifferentiationPanel.Title = 'Integration and Differentiation';
            app.BaselineCorrectionandZeroPaddingPanel = uipanel(app.UIFigure);
            app.BaselineCorrectionandZeroPaddingPanel.Title = 'Baseline Correction and Zero Padding';
            app.PolynomialcorrectionSpinnerLabel = uilabel(app.BaselineCorrectionandZeroPaddingPanel);
            app.PolynomialcorrectionSpinnerLabel.Text = 'Polynomial correction';
            app.PolynomialcorrectionSpinner = uispinner(app.BaselineCorrectionandZeroPaddingPanel);
            app.PolynomialcorrectionSpinner.Limits = [0 Inf];
            app.PolynomialcorrectionSpinner.ValueDisplayFormat = '%.0f';
            app.ZeropaddingnumberofpointsEditFieldLabel = uilabel(app.BaselineCorrectionandZeroPaddingPanel);
            app.ZeropaddingnumberofpointsEditFieldLabel.Text = 'Zero padding (number of points)';
            app.ZeropaddingnumberofpointsEditField = uieditfield(app.BaselineCorrectionandZeroPaddingPanel, 'numeric');
            app.ZeropaddingnumberofpointsEditField.Limits = [0 Inf];
            app.ZeropaddingnumberofpointsEditField.ValueDisplayFormat = '%.0f';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end