classdef SoilProfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreateSoilProfilePanel          matlab.ui.container.Panel
        linearLabel                     matlab.ui.control.Label
        DampingEditFieldLabel           matlab.ui.control.Label
        DampingEditField                matlab.ui.control.NumericEditField
        AddLayersButton                 matlab.ui.control.Button
        AddBedrockButton                matlab.ui.control.Button
        SoilProfileLabel                matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        OkButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        LayerThicknessmEditFieldLabel   matlab.ui.control.Label
        LayerThicknessmEditField        matlab.ui.control.NumericEditField
        ShearVelocityms2EditFieldLabel  matlab.ui.control.Label
        ShearVelocityms2EditField       matlab.ui.control.NumericEditField
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        Densitykgm3EditField            matlab.ui.control.NumericEditField
        MaterialIndexEditFieldLabel     matlab.ui.control.Label
        MaterialIndexEditField          matlab.ui.control.NumericEditField
        NumberofSublayersEditFieldLabel  matlab.ui.control.Label
        NumberofSublayersEditField      matlab.ui.control.NumericEditField
        ExportButton                    matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        UITable_2                       matlab.ui.control.Table
        BedrockLabel                    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app object
    end
    
    methods (Access = private)
        
        function updateFigure(app)
            % Get Table UI component data
            t = app.UITable.DisplayData;
            % Plot modified data 
            x = t.Vs;
            x=[x(1);x];
            y = [0; cumsum(t.thickness)];
            plot(app.UIAxes,x,-y,'LineWidth',2,'Color','k');
            app.UIAxes.XLim=[0,max(x)+100];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
               % Store main app object
               app.CallingApp = mainapp;
               %check if tmp file has been stored and load
               fileID = fopen('SoilProfile.sra');
               if fileID>=3
                   tdata=readtable('SoilProfile.sra',"FileType","text");
                    %create table
                varNames = {'thickness','Vs','ro','damping','material'};
                tdata.Properties.VariableNames=varNames;
                % Add data to the Table UI Component
                app.UITable.Data = tdata;
                % Plot the data
                updateFigure(app);
                fclose(fileID);
               %check if tmp file has been stored and load
               end
               fileID = fopen('Bedrock.sra');
               if fileID>=3
                   tdata2=readtable('Bedrock.sra',"FileType","text");
                    %create table
                varNames2 = {'Vs','ro','damping','material'};
                tdata2.Properties.VariableNames=varNames2;
                % Add data to the Table UI Component
                app.UITable_2.Data = tdata2;
                fclose(fileID);
               end
        end

        % Button pushed function: AddLayersButton
        function AddLayersButtonPushed(app, event)
            nl=round(app.NumberofSublayersEditField.Value);
            thickness=ones(nl,1).*(app.LayerThicknessmEditField.Value/nl);
            Vs=ones(nl,1).*app.ShearVelocityms2EditField.Value;
            ro=ones(nl,1).*app.Densitykgm3EditField.Value;
            material=ones(nl,1).*app.MaterialIndexEditField.Value;
            damping=ones(nl,1).*app.DampingEditField.Value;
            % create table
            tdata = table(thickness,Vs,ro,damping,material); 
            % Add data to the Table UI Component
            oldData = app.UITable.Data;
            app.UITable.Data = [oldData;tdata];
            % Plot the data
            updateFigure(app);
        end

        % Button pushed function: AddBedrockButton
        function AddBedrockButtonPushed(app, event)
            Vs=app.ShearVelocityms2EditField.Value;
            ro=app.Densitykgm3EditField.Value;
            material=app.MaterialIndexEditField.Value;
            damping=app.DampingEditField.Value;
            % create table
            tdata = table(Vs,ro,damping,material); 
            % Add data to the Table UI Component 
            app.UITable_2.Data = tdata;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path,indx] = uigetfile({'*.mat','MAT-Files (*.mat)';
   '*.txt;*.rtf;*.odt;*.csv','Worpad Documents (*.txt,*.rtf,*.odt,*.csv)';
            '.xls;*.xlsx','Worksheets (.xls,*.xlsx)';
   '*.*',  'All Files (*.*)'}, ...
   'Select a File');
            switch indx
                case 1
                    tdata=load(strcat(path,file));
                case 2
                    tdata=readtable(strcat(path,file),'Format','%f%f%f%u');
                case 3
                    tdata=readtable(strcat(path,file),'Format','auto');
                case 4
                    tdata=readtable(strcat(path,file),'Format','auto');
                otherwise
                    tdata=table(0,0,0,0);
            end
            %create table
            varNames = {'thickness','Vs','ro','damping','material'};
            tdata.Properties.VariableNames=varNames;
            % Add data to the Table UI Component
            app.UITable.Data = tdata;
            % Plot the data
            updateFigure(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
             [file,path,indx] = uiputfile({'*.mat','MAT-Files (*.mat)';
   '*.txt','Text File (*.txt)';
   '*.csv','Text File (*.csv)';
   '*.xlsx','Worksheets (*.xlsx)'}, ...
   'Select a File');
             filename = fullfile(path,file);
             tdata=app.UITable.Data;
             switch indx
                case 1
                    save(filename,'tdata');
                case 2
                    writetable(tdata,filename,'FileType','text');
                case 3
                    writetable(tdata,filename,'FileType','auto');
                case 4
                    writetable(tdata,filename,'FileType',"spreadsheet");
                otherwise
                    %tdata=table(0,0,0,0);
             end  
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data=[];
            plot(app.UIAxes,0,0);
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Get Table UI component data
            t = app.UITable.DisplayData; 
            tb = app.UITable_2.DisplayData; 
            if and(~isempty(t),~isempty(tb))
            % Call main app's public function
            UpdateProfile(app.CallingApp,t.thickness,[t.Vs;tb.Vs],[t.ro; tb.ro],[t.damping; tb.damping],[t.material; tb.material]);
            %create tmp file
            tdata=app.UITable.Data;
            writetable(tdata,'SoilProfile.sra','FileType','text');
            tdata2=app.UITable_2.Data;
            writetable(tdata2,'Bedrock.sra','FileType','text');
            % Delete the dialog box  
            delete(app)     
            else
            fig = uifigure;
            uialert(fig,'Incomplete Data','Soil Profile will NOT be exported','Icon','warning'); 
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Delete the dialog box  
            delete(app)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreateSoilProfilePanel = uipanel(app.UIFigure);
            app.CreateSoilProfilePanel.Title = 'Create Soil Profile';
            app.linearLabel = uilabel(app.CreateSoilProfilePanel);
            app.linearLabel.Text = '0 = linear';
            app.DampingEditFieldLabel = uilabel(app.CreateSoilProfilePanel);
            app.DampingEditFieldLabel.Text = 'Damping';
            app.DampingEditField = uieditfield(app.CreateSoilProfilePanel, 'numeric');
            app.DampingEditField.Limits = [0 Inf];
            app.AddLayersButton = uibutton(app.CreateSoilProfilePanel, 'push');
            app.AddLayersButton.ButtonPushedFcn = createCallbackFcn(app, @AddLayersButtonPushed, true);
            app.AddLayersButton.Text = 'Add Layers';
            app.AddBedrockButton = uibutton(app.CreateSoilProfilePanel, 'push');
            app.AddBedrockButton.ButtonPushedFcn = createCallbackFcn(app, @AddBedrockButtonPushed, true);
            app.AddBedrockButton.Text = 'Add Bedrock';
            app.SoilProfileLabel = uilabel(app.UIFigure);
            app.SoilProfileLabel.Text = 'Soil Profile';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Layer Thickness [m]'; 'Shear Velocity [m/s2]'; 'Density [kg/m3]'; 'Damping'; 'Material'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.LayerThicknessmEditFieldLabel = uilabel(app.UIFigure);
            app.LayerThicknessmEditFieldLabel.Text = 'Layer Thickness [m]';
            app.LayerThicknessmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LayerThicknessmEditField.Limits = [0 Inf];
            app.ShearVelocityms2EditFieldLabel = uilabel(app.UIFigure);
            app.ShearVelocityms2EditFieldLabel.Text = 'Shear Velocity [m/s2]';
            app.ShearVelocityms2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ShearVelocityms2EditField.Limits = [0 Inf];
            app.Densitykgm3EditFieldLabel = uilabel(app.UIFigure);
            app.Densitykgm3EditFieldLabel.Text = 'Density [kg/m3]';
            app.Densitykgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Densitykgm3EditField.Limits = [0 Inf];
            app.MaterialIndexEditFieldLabel = uilabel(app.UIFigure);
            app.MaterialIndexEditFieldLabel.Text = 'Material Index';
            app.MaterialIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaterialIndexEditField.Limits = [0 Inf];
            app.NumberofSublayersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSublayersEditFieldLabel.Text = 'Number of Sublayers';
            app.NumberofSublayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSublayersEditField.Limits = [1 Inf];
            app.NumberofSublayersEditField.Value = 1;
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'USRA-Logo.jpg';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Shear Velocity [m/s2]'; 'Density [kg/m3]'; 'Damping'; 'Material'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.BedrockLabel = uilabel(app.UIFigure);
            app.BedrockLabel.Text = 'Bedrock';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Soil Profile')
            xlabel(app.UIAxes, 'Shear Velocity')
            ylabel(app.UIAxes, 'Depth')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoilProfile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end