classdef SolverSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IterativeprocedurePanel         matlab.ui.container.Panel
        SolverSettingsLabel             matlab.ui.control.Label
        EffectiveshearstrainratioEditFieldLabel  matlab.ui.control.Label
        EffectiveshearstrainratioEditField  matlab.ui.control.NumericEditField
        SmoothingshearstrainratioEditFieldLabel  matlab.ui.control.Label
        SmoothingshearstrainratioEditField  matlab.ui.control.NumericEditField
        MaximumnumberofcyclesEditFieldLabel  matlab.ui.control.Label
        MaximumnumberofcyclesEditField  matlab.ui.control.NumericEditField
        ToleranceLabel                  matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        SetButton                       matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        DefaultButton                   matlab.ui.control.Button
        ParallelComputingPanel          matlab.ui.container.Panel
        NumberofWorkersSpinnerLabel     matlab.ui.control.Label
        NumberofWorkersSpinner          matlab.ui.control.Spinner
        serialexecutionLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, marray)
            app.CallingApp=mainapp;
            R=marray.R;
            app.EffectiveshearstrainratioEditField.Value=R;
            R2=marray.R2;
            app.SmoothingshearstrainratioEditField.Value=R2;
            loops=marray.maxloops;
            app.MaximumnumberofcyclesEditField.Value=loops;
            tol=marray.tolmax;
            app.ToleranceEditField.Value=tol;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            R=app.EffectiveshearstrainratioEditField.Value;
            R2=app.SmoothingshearstrainratioEditField.Value;
            loops=app.MaximumnumberofcyclesEditField.Value;
            tol=app.ToleranceEditField.Value;
            M=app.NumberofWorkersSpinner.Value;
            values=struct('R',R,'R2',R2,'loops',round(loops,0),'tol',tol,'M',M);
            %update main app
            UpdateSolver(app.CallingApp,values);
            % Delete the dialog box  
            delete(app)  
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             % Delete the dialog box  
            delete(app)  
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            R=0.65;
            app.EffectiveshearstrainratioEditField.Value=R;
            R2=1;
            app.SmoothingshearstrainratioEditField.Value=R2;
            loops=50;
            app.MaximumnumberofcyclesEditField.Value=loops;
            tol=0.0001;
            app.ToleranceEditField.Value=tol;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IterativeprocedurePanel = uipanel(app.UIFigure);
            app.IterativeprocedurePanel.Title = 'Iterative procedure';
            app.SolverSettingsLabel = uilabel(app.UIFigure);
            app.SolverSettingsLabel.Text = 'Solver Settings';
            app.EffectiveshearstrainratioEditFieldLabel = uilabel(app.UIFigure);
            app.EffectiveshearstrainratioEditFieldLabel.Text = 'Effective shear strain ratio';
            app.EffectiveshearstrainratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.EffectiveshearstrainratioEditField.Limits = [0 Inf];
            app.EffectiveshearstrainratioEditField.Value = 0.65;
            app.SmoothingshearstrainratioEditFieldLabel = uilabel(app.UIFigure);
            app.SmoothingshearstrainratioEditFieldLabel.Text = 'Smoothing shear strain ratio';
            app.SmoothingshearstrainratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmoothingshearstrainratioEditField.Limits = [0 Inf];
            app.SmoothingshearstrainratioEditField.Value = 1;
            app.MaximumnumberofcyclesEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumnumberofcyclesEditFieldLabel.Text = 'Maximum number of cycles';
            app.MaximumnumberofcyclesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumnumberofcyclesEditField.Limits = [0 Inf];
            app.MaximumnumberofcyclesEditField.ValueDisplayFormat = '%.0f';
            app.MaximumnumberofcyclesEditField.Value = 50;
            app.ToleranceLabel = uilabel(app.UIFigure);
            app.ToleranceLabel.Text = 'Tolerance [%]';
            app.ToleranceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.Value = 0.0001;
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.ParallelComputingPanel = uipanel(app.UIFigure);
            app.ParallelComputingPanel.Title = 'Parallel Computing';
            app.NumberofWorkersSpinnerLabel = uilabel(app.ParallelComputingPanel);
            app.NumberofWorkersSpinnerLabel.Text = 'Number of Workers';
            app.NumberofWorkersSpinner = uispinner(app.ParallelComputingPanel);
            app.NumberofWorkersSpinner.Limits = [0 Inf];
            app.serialexecutionLabel = uilabel(app.ParallelComputingPanel);
            app.serialexecutionLabel.Text = '0 == serial  execution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolverSet(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end