classdef THimport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AccelerationTimeHistoryLabel    matlab.ui.control.Label
        DeterministicAnalysisPanel      matlab.ui.container.Panel
        ImportButton                    matlab.ui.control.Button
        MonteCarloSimulationButtonGroup  matlab.ui.container.ButtonGroup
        SelectFolderButton              matlab.ui.control.RadioButton
        ImportFileButton                matlab.ui.control.RadioButton
        AddButton                       matlab.ui.control.Button
        NumberofaccelerogramsEditFieldLabel  matlab.ui.control.Label
        NumberofaccelerogramsEditField  matlab.ui.control.NumericEditField
        TimedurationsEditFieldLabel     matlab.ui.control.Label
        TimedurationsEditField          matlab.ui.control.NumericEditField
        maxmagnitudegEditFieldLabel     matlab.ui.control.Label
        maxmagnitudegEditField          matlab.ui.control.NumericEditField
        meanmagnitudegLabel             matlab.ui.control.Label
        meanmagnitudegEditField         matlab.ui.control.NumericEditField
        OkButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        clearallButton                  matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        minLabel                        matlab.ui.control.Label
        maxLabel                        matlab.ui.control.Label
        meanpredominantfrequencyHzEditFieldLabel  matlab.ui.control.Label
        meanpredominantfrequencyHzEditField  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app object
        THdataset % matrix of acceleration THs
        THdatastruct % struct of acceleration THs
        nacc % number of acc
        magmin %min magnitude [g]
        magmax %max magnitude [g]
        durmin %min duration
        durmax %min duration
        magmean %mean magnitude [g]
        predfreq %predominant frequency [Hz]
        flagTH % 0 matrix; 1 struct
    end
    
    methods (Access = private)
        
        function UpdateFigureStr(app)
            na=app.nacc;
            if ~isempty(na)
                cc=zeros(na,1);
                for hh=1:na
                t=app.THdatastruct(hh).t;
                acc=app.THdatastruct(hh).acc;
                plot(app.UIAxes,t,acc./9.81,'LineWidth',0.5);
                hold(app.UIAxes,'on')
                [fomega,omega]=FFTcompl(acc./9.81,t);
                plot(app.UIAxes_2,omega./2/pi,abs(fomega),'LineWidth',0.5);
                app.UIAxes_2.XLim=[0,max(omega)./2/pi];
                ind=abs(fomega)==max(abs(fomega));
                cc(hh)=min(abs(omega(ind)))./2./pi;
                hold(app.UIAxes_2,'on')
                end
                hold(app.UIAxes,'off')
                hold(app.UIAxes_2,'off')
                app.predfreq=mean(cc);
                app.meanpredominantfrequencyHzEditField.Value=mean(cc);
            else
                plot(app.UIAxes,0,0);
                plot(app.UIAxes_2,0,0);
                app.meanpredominantfrequencyHzEditField.Value=0;
            end
        end
        
          function UpdateFigure(app)
            t=app.THdataset(:,1);
            acc=app.THdataset(:,2:end);
            plot(app.UIAxes,t,acc./9.81,'LineWidth',0.5);
            na=app.nacc;
            if ~isempty(na)
                cc=zeros(na,1);
                for hh=1:na
                [fomega,omega]=FFTcompl(acc(:,hh)./9.81,t);
                plot(app.UIAxes_2,omega./2/pi,abs(fomega),'LineWidth',0.5);
                app.UIAxes_2.XLim=[0,max(omega)./2/pi];
                ind=abs(fomega)==max(abs(fomega));
                cc(hh)=min(abs(omega(ind)))./2./pi;
                hold(app.UIAxes_2,'on')
                end
                hold(app.UIAxes_2,'off')
                app.predfreq=mean(cc);
                app.meanpredominantfrequencyHzEditField.Value=mean(cc);
            else
                plot(app.UIAxes,0,0);
                plot(app.UIAxes_2,0,0);
                app.meanpredominantfrequencyHzEditField.Value=0;
            end
          end
    
        
        function UpdateFields(app)
            tdata=app.THdataset;
            t=tdata(:,1);
            na=app.nacc;
            app.NumberofaccelerogramsEditField.Value=na;
            if na==1
                app.durmax=max(tdata(:,1));
                app.durmin=app.durmax;
                app.TimedurationsEditField.Value=round(app.durmin,3);
                app.EditField.Value=round(app.durmax,3);
                app.magmax=max(abs(tdata(:,2)))./9.81;
                app.magmin=app.magmax;
                app.magmean=app.magmax;
                app.EditField_2.Value=round(app.magmax,3);
                app.maxmagnitudegEditField.Value=round(app.magmax,3);
                app.meanmagnitudegEditField.Value=round(app.magmean,3);
            else
                 m=max(abs(tdata(2:end)),[],1);
                app.durmax=max(t);
                app.durmin=max(t);
                app.TimedurationsEditField.Value=round(app.durmin,3);
                app.EditField.Value=round(app.durmax,3);
                app.magmax=max(m)./9.81;
                app.magmin=min(m)./9.81;
                app.magmean=mean(m)./9.81;
                app.EditField_2.Value=round(app.magmax,3);
                app.maxmagnitudegEditField.Value=round(app.magmax,3);
                app.meanmagnitudegEditField.Value=round(app.magmean,3);
            end
        end
        
        function UpdateFieldsStr(app)
            na=app.nacc;
            m=zeros(na,1);
            t=m;
            tdata=app.THdatastruct;
            for hh = 1 : na
                tmp(:,1)=tdata.t;
                tmp(:,2)=tdata.acc;
                m(hh)=max(abs(tmp(:,2)));
                t(hh)=max(tmp(:,1));
            end
            app.durmax=max(t);
            app.durmin=min(t);
            app.TimedurationsEditField.Value=round(app.durmin,3);
            app.EditField.Value=round(app.durmax,3);
            app.magmax=max(m)./9.81;
            app.magmin=min(m)./9.81;
            app.magmean=mean(m)./9.81;
            app.EditField_2.Value=round(app.magmax,3);
            app.maxmagnitudegEditField.Value=round(app.magmax,3);
            app.meanmagnitudegEditField.Value=round(app.magmean,3);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, marray)
               % Store main app object
               app.CallingApp = mainapp;
               if isempty(marray)
                   app.ImportButton.Enable='on';
                   app.AddButton.Enable='on';
                   app.nacc=0;
               else
                   if isstruct(marray)
                       app.nacc=length(marray);
                       app.flagTH=1;
                       app.THdatastruct=marray;
                       UpdateFigureStr(app);
                       UpdateFieldsStr(app)
                   else
                       app.nacc=size(marray,2)-1;
                       app.flagTH=0;
                       app.THdataset=marray;
                       UpdateFigure(app);
                       UpdateFields(app);
                    end 
               app.ImportButton.Enable='off';
               app.AddButton.Enable='off';
               end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.SelectFolderButton.Value
                dp=app.CallingApp.projectpath;
                directoryname = uigetdir(dp, 'Pick a Directory');
                if directoryname ~= 0
                matfiles = dir(fullfile(directoryname, '*.txt'));                
                na = length(matfiles);
                app.nacc=na;
                m=zeros(na,1);
                t=m;
                oldpointer = get(app.UIFigure, 'pointer');
                set(app.UIFigure, 'pointer', 'watch');
                drawnow;
                for hh = 1 : na
                    tmp=readmatrix(fullfile(directoryname, matfiles(hh).name));
                    app.THdatastruct(hh).t=tmp(:,1);
                    app.THdatastruct(hh).acc=tmp(:,2);
                    m(hh)=max(abs(tmp(:,2)));
                    t(hh)=max(tmp(:,1));
                end
                app.flagTH=1;
                UpdateFigureStr(app);
                UpdateFieldsStr(app)
                app.ImportButton.Enable='off';
                app.AddButton.Enable='off';
                app.nacc=na;
                set(app.UIFigure, 'pointer', oldpointer);
                else
                    app.THdatastruct=struct();
                    app.nacc=0;
                    app.flagTH=1;
                end
            elseif app.ImportFileButton.Value
                [file,path,indx] = uigetfile({'*.txt;*.rtf;*.odt;*.csv',...
                    'Worpad Documents (*.txt,*.rtf,*.odt,*.csv)';
            '.xls;*.xlsx','Worksheets (.xls,*.xlsx)';
            '*.mat','MAT-Files (*.mat)';
   '*.*',  'All Files (*.*)'}, ...
   'Select a File');
            switch indx
                case 1
                    tdata=load(strcat(path,file));
                case 2
                    tdata=readmatrix(strcat(path,file));
                case 3
                    tdata=readmatrix(strcat(path,file));
                case 4
                    tdata=readmatrix(strcat(path,file));
                otherwise
                    tdata=[];
            end
                if indx==0
                    app.THdataset=tdata;
                    app.nacc=0;
                    app.flagTH=0;
                    app.ImportButton.Enable='on';
                    app.AddButton.Enable='on';
                else
                    app.THdataset=tdata;
                    app.nacc=size(tdata,2)-1;
                    app.flagTH=0;
                    UpdateFigure(app);
                    UpdateFields(app);
                    app.ImportButton.Enable='off';
                    app.AddButton.Enable='off';     
                end
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
                        [file,path,indx] = uigetfile({'*.txt;*.rtf;*.odt;*.csv','Worpad Documents (*.txt,*.rtf,*.odt,*.csv)';
            '.xls;*.xlsx','Worksheets (.xls,*.xlsx)';
            '*.mat','MAT-Files (*.mat)';
   '*.*',  'All Files (*.*)'}, ...
   'Select a File');
            switch indx
                case 1
                    tdata=load(strcat(path,file));
                case 2
                    tdata=readmatrix(strcat(path,file));
                case 3
                    tdata=readmatrix(strcat(path,file));
                case 4
                    tdata=readmatrix(strcat(path,file));
                otherwise
                    tdata=[];
            end
            if indx ~= 0
            app.THdataset=tdata;
            app.nacc=1;
            UpdateFigure(app);
            UpdateFields(app);
            app.ImportButton.Enable='off';
            app.AddButton.Enable='off';
            app.flagTH=0;
            end
        end

        % Button pushed function: clearallButton
        function clearallButtonPushed(app, event)
            app.THdataset=[];
            app.THdatastruct=[];
            app.ImportButton.Enable='on';
            app.AddButton.Enable='on';
            plot(app.UIAxes,0,0);
            plot(app.UIAxes_2,0,0);
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            if app.flagTH==0
                % Call main app's public function
                marray=app.THdataset;
                UpdateTH(app.CallingApp,marray);
            elseif app.flagTH==1
                marray=app.THdatastruct;
                UpdateTH(app.CallingApp,marray);
            else
                UpdateTH(app.CallingApp,[]);
            end
            % Delete the dialog box  
            delete(app)     
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Delete the dialog box  
            delete(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccelerationTimeHistoryLabel = uilabel(app.UIFigure);
            app.AccelerationTimeHistoryLabel.Text = 'Acceleration Time History';
            app.DeterministicAnalysisPanel = uipanel(app.UIFigure);
            app.DeterministicAnalysisPanel.Title = 'Deterministic Analysis';
            app.ImportButton = uibutton(app.DeterministicAnalysisPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.MonteCarloSimulationButtonGroup = uibuttongroup(app.UIFigure);
            app.MonteCarloSimulationButtonGroup.Title = 'Monte Carlo Simulation';
            app.SelectFolderButton = uiradiobutton(app.MonteCarloSimulationButtonGroup);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SelectFolderButton.Value = true;
            app.ImportFileButton = uiradiobutton(app.MonteCarloSimulationButtonGroup);
            app.ImportFileButton.Text = 'Import File';
            app.AddButton = uibutton(app.MonteCarloSimulationButtonGroup, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.NumberofaccelerogramsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofaccelerogramsEditFieldLabel.Text = 'Number of accelerograms';
            app.NumberofaccelerogramsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofaccelerogramsEditField.Editable = 'off';
            app.TimedurationsEditFieldLabel = uilabel(app.UIFigure);
            app.TimedurationsEditFieldLabel.Text = 'Time duration [s]';
            app.TimedurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimedurationsEditField.Editable = 'off';
            app.maxmagnitudegEditFieldLabel = uilabel(app.UIFigure);
            app.maxmagnitudegEditFieldLabel.Text = 'max magnitude [g]';
            app.maxmagnitudegEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxmagnitudegEditField.Editable = 'off';
            app.meanmagnitudegLabel = uilabel(app.UIFigure);
            app.meanmagnitudegLabel.Text = 'mean magnitude [g]';
            app.meanmagnitudegEditField = uieditfield(app.UIFigure, 'numeric');
            app.meanmagnitudegEditField.Editable = 'off';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.clearallButton = uibutton(app.UIFigure, 'push');
            app.clearallButton.ButtonPushedFcn = createCallbackFcn(app, @clearallButtonPushed, true);
            app.clearallButton.Text = 'clear all';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Editable = 'off';
            app.minLabel = uilabel(app.UIFigure);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.UIFigure);
            app.maxLabel.Text = 'max';
            app.meanpredominantfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.meanpredominantfrequencyHzEditFieldLabel.Text = 'mean predominant frequency [Hz]';
            app.meanpredominantfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.meanpredominantfrequencyHzEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time History')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'acceleration [g]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'FFT')
            xlabel(app.UIAxes_2, 'Frequency [Hz]')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = THimport(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end