classdef mainSRA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        USRAUncertaintySiteResponseAnalysisUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        Example1Menu                    matlab.ui.container.Menu
        Example2Menu                    matlab.ui.container.Menu
        Example3Menu                    matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        StratigraphyMenu                matlab.ui.container.Menu
        CyclicParametersMenu            matlab.ui.container.Menu
        SoilProfileMenu                 matlab.ui.container.Menu
        PresaichFormalismMenu           matlab.ui.container.Menu
        SeismicInputMenu                matlab.ui.container.Menu
        TimeHistoryFunctionsMenu        matlab.ui.container.Menu
        PowerSpectralDensityMenu        matlab.ui.container.Menu
        PSDDefinitionMenu               matlab.ui.container.Menu
        GenerateTHsMenu                 matlab.ui.container.Menu
        SoilUncertaintyMenu             matlab.ui.container.Menu
        ProbabilityApproachMenu         matlab.ui.container.Menu
        PSDDefinitionMenu_2             matlab.ui.container.Menu
        GenerateRandomFieldMenu         matlab.ui.container.Menu
        FuzzyApproachMenu               matlab.ui.container.Menu
        AdvancedMenu                    matlab.ui.container.Menu
        SignalProcessingMenu            matlab.ui.container.Menu
        SolverSettingsMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ReferencesMenu                  matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        SourceofUncertaintyPanel        matlab.ui.container.Panel
        SoilPropertiesButton            matlab.ui.control.StateButton
        SeismicInputButton              matlab.ui.control.StateButton
        InputPositionSwitch_2           matlab.ui.control.Switch
        InputPositionSwitch_2Label      matlab.ui.control.Label
        UncertaintySiteResponseAnalysisUSRALabel  matlab.ui.control.Label
        ChecksPanel                     matlab.ui.container.Panel
        SoilUncertaintyLamp             matlab.ui.control.Lamp
        SoilUncertaintyLampLabel        matlab.ui.control.Label
        ParallelComputingLamp           matlab.ui.control.Lamp
        ParallelComputingLampLabel      matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        CheckButton                     matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        StochasticInputLamp             matlab.ui.control.Lamp
        StochasticInputLampLabel        matlab.ui.control.Label
        SeismicInputLamp                matlab.ui.control.Lamp
        SeismicInputLampLabel           matlab.ui.control.Label
        StratigrafyLamp                 matlab.ui.control.Lamp
        StratigrafyLampLabel            matlab.ui.control.Label
        AnalysisOptionsPanel            matlab.ui.container.Panel
        SoilPropertiesSwitch            matlab.ui.control.Switch
        SoilPropertiesSwitchLabel       matlab.ui.control.Label
        WavePropagationSwitch           matlab.ui.control.Switch
        WavePropagationSwitchLabel      matlab.ui.control.Label
        ActivateAnalysisPanel           matlab.ui.container.Panel
        AnalysisTypeKnob                matlab.ui.control.DiscreteKnob
        AnalysisTypeKnobLabel           matlab.ui.control.Label
        ActivateParallelComputingSwitch  matlab.ui.control.RockerSwitch
        ActivateParallelComputingSwitchLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        SelectSoilDepositSamplesButtonGroup  matlab.ui.container.ButtonGroup
        tosoilSpinner                   matlab.ui.control.Spinner
        tosoilSpinnerLabel              matlab.ui.control.Label
        fromsoilSpinner                 matlab.ui.control.Spinner
        fromsoilSpinnerLabel            matlab.ui.control.Label
        soilSpinner                     matlab.ui.control.Spinner
        soilSpinnerLabel                matlab.ui.control.Label
        SingleButton_2                  matlab.ui.control.RadioButton
        RangeButton_2                   matlab.ui.control.RadioButton
        AllButton_2                     matlab.ui.control.RadioButton
        SelectTimeHistorySamplesButtonGroup  matlab.ui.container.ButtonGroup
        toaccSpinner                    matlab.ui.control.Spinner
        toaccSpinnerLabel               matlab.ui.control.Label
        fromaccSpinner                  matlab.ui.control.Spinner
        fromaccSpinnerLabel             matlab.ui.control.Label
        accSpinner                      matlab.ui.control.Spinner
        accSpinnerLabel                 matlab.ui.control.Label
        SingleButton                    matlab.ui.control.RadioButton
        RangeButton                     matlab.ui.control.RadioButton
        AllButton                       matlab.ui.control.RadioButton
        TabGroup                        matlab.ui.container.TabGroup
        THTab                           matlab.ui.container.Tab
        ExportButton_6                  matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        HoldButton                      matlab.ui.control.StateButton
        ClearButton                     matlab.ui.control.Button
        ShowButton                      matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        TransferFunctionTab             matlab.ui.container.Tab
        ExportButton_7                  matlab.ui.control.Button
        ExportButton_2                  matlab.ui.control.Button
        HoldButton_3                    matlab.ui.control.StateButton
        ClearButton_4                   matlab.ui.control.Button
        ShowButton_TF                   matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        PSDTab                          matlab.ui.container.Tab
        ExportButton_3                  matlab.ui.control.Button
        ZoomSlider                      matlab.ui.control.Slider
        ZoomLabel                       matlab.ui.control.Label
        HoldButton_2                    matlab.ui.control.StateButton
        ClearButton_2                   matlab.ui.control.Button
        ShowButton_PSD                  matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        ResponseSpectrumTab             matlab.ui.container.Tab
        AnalysisTypePanel               matlab.ui.container.Panel
        StochasticfromPSDButton         matlab.ui.control.StateButton
        DeterministicfromTHsButton      matlab.ui.control.StateButton
        ExportButton_4                  matlab.ui.control.Button
        PlotResponseSpectrumPanel       matlab.ui.container.Panel
        AverageButton                   matlab.ui.control.StateButton
        IndividualButton                matlab.ui.control.StateButton
        aggEditField                    matlab.ui.control.NumericEditField
        aggEditFieldLabel               matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        ComparewithDropDown             matlab.ui.control.DropDown
        ComparewithDropDownLabel        matlab.ui.control.Label
        HoldButton_4                    matlab.ui.control.StateButton
        ClearButton_3                   matlab.ui.control.Button
        ShowButton_RSA                  matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        SoilDepositDataTab              matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        SoilDepositSpinner              matlab.ui.control.Spinner
        SoilDepositSpinnerLabel         matlab.ui.control.Label
        EquivalentLinearPropertiesPanel  matlab.ui.container.Panel
        AverageShearVelocityms2EditField_2  matlab.ui.control.NumericEditField
        AverageShearVelocityms2EditField_2Label  matlab.ui.control.Label
        FrequencyatthepeakoftheTransferFunctionHzEditField_2  matlab.ui.control.NumericEditField
        FrequencyatthepeakoftheTransferFunctionHzEditField_2Label  matlab.ui.control.Label
        FirstFundamentalFrequencyHzEditField_2  matlab.ui.control.NumericEditField
        FirstFundamentalFrequencyHzEditField_2Label  matlab.ui.control.Label
        FirstNaturalPeriodsEditField_2  matlab.ui.control.NumericEditField
        FirstNaturalPeriodsEditField_2Label  matlab.ui.control.Label
        LinearPropertiesPanel           matlab.ui.container.Panel
        AverageShearVelocityms2EditField  matlab.ui.control.NumericEditField
        AverageShearVelocityms2EditFieldLabel  matlab.ui.control.Label
        FrequencyatthepeakoftheTransferFunctionHzEditField  matlab.ui.control.NumericEditField
        FrequencyatthepeakoftheTransferFunctionHzEditFieldLabel  matlab.ui.control.Label
        FirstFundamentalFrequencyHzEditField  matlab.ui.control.NumericEditField
        FirstFundamentalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        FirstNaturalPeriodsEditField    matlab.ui.control.NumericEditField
        FirstNaturalPeriodsEditFieldLabel  matlab.ui.control.Label
        DataDistributionTab             matlab.ui.container.Tab
        ExportButton_5                  matlab.ui.control.Button
        ProbabilityzerocrossingPanel    matlab.ui.container.Panel
        MedianvalueofthepeakaccelerationEditField  matlab.ui.control.NumericEditField
        MedianvalueofthepeakaccelerationEditFieldLabel  matlab.ui.control.Label
        ZerothorderspectralmomentEditField  matlab.ui.control.NumericEditField
        ZerothorderspectralmomentEditFieldLabel  matlab.ui.control.Label
        StationarytimewindowsEditField  matlab.ui.control.NumericEditField
        StationarytimewindowsEditFieldLabel  matlab.ui.control.Label
        HoldButton_5                    matlab.ui.control.StateButton
        ClearButton_5                   matlab.ui.control.Button
        ShowButton_5                    matlab.ui.control.Button
        TypeofanalysisButtonGroup       matlab.ui.container.ButtonGroup
        ProbabilityfromPSDButton        matlab.ui.control.RadioButton
        StatisticalfromTHButton         matlab.ui.control.RadioButton
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        SelectPositionButtonGroup       matlab.ui.container.ButtonGroup
        DepthmEditField                 matlab.ui.control.NumericEditField
        DepthmEditFieldLabel            matlab.ui.control.Label
        InsideButton                    matlab.ui.control.RadioButton
        BedrockButton                   matlab.ui.control.RadioButton
        SurfaceButton                   matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SoilProfileDialog; % Open soil profiledialog app
        NLcurvesDialog; %open equivalent curve dialog app
        THImportDialog; %open equivalent TH dialog app
        SignalProcDialog; %open signal processing dialog app
        SolverSettingDialog; %open signal processing dialog app
        PSDinputDialog; %open PSD  dialog app
        THGenDialog; %open generation TH
        RVTgenDialog; %open gen from RVT  dialog app
        %% soil properties
        Vs; %shear velocity array
        G; %shear modulus array
        vz; %array of the layer thicknesses
        Ro; %array of the soil densities
        Mat; %array of the material indexes
        Csi; %array of the damping values
        NLcurve; %struct array
        %% input data
        THarray; %matrix or struct 
        PSDarray; %matrix
        nacc; %integer, numb of accs
        %% output data
        SRAoutput; %results (struct)
        PSDoutput; %PSD result (struct)
        THoutput; %TH result (struct)
        %%flags
        flagTH; % 0 matrix; 1 struct
        flagOut; %0: outcrop, 1:inside;
        flagLin; %0:linear, 1:equivalent linear
        flagWave; %0:convolution, 1:deconvolution
        flagSoil; % 1 if soil profile has been set
        flagInput; % 1 if input  has been set
        flagInputPSD; % 1 if input  has been set
        flagNL; % 1 if NL curves have been set
        flagPar; %0: serial , 1: parallel
        %%settings
        solverdata; %struct array
        Mworkers; %number of workers for parallel computing
        %%
        clPSD; %limits PSD figure
    end
    
    properties (Access = public)
        projectpath % path of the saved project
    end
    
    methods (Access = public)
        
        function UpdateProfile(app,vzi,vs,ro,damp,mat)
            % Store inputs as properties
            app.Vs=vs;
            app.vz=vzi;
            app.Ro=ro;
            app.Mat=mat;
            app.Csi=damp;
            if ~isempty(vs)
                app.flagSoil=1;
                app.G=vs.^2.*ro;
            end
        end
        
        function UpdateSoil(app,nc)
            app.NLcurve=nc;
            if ~isempty(fieldnames(nc))
                app.flagNL=1;
            end
        end
        
        function UpdateTH(app,TH)
            app.THarray=TH;
            if isstruct(TH) 
                app.flagTH=1;
                if  ~isempty(fieldnames(TH))
                    app.flagInput=1;
                    app.nacc=length(TH);
                end
            else
                app.flagTH=0;
                if ~isempty(TH)
                    app.flagInput=1;
                    app.nacc=size(TH,2)-1;
                end
            end
        end
        
        function UpdateSignal(app,values)
            tdata=app.solverdata;
            np=values.polin;
            tdata.polin=np;
            npp=values.npoints;
            tdata.zeropad=npp;
            app.solverdata=tdata;
        end
        
        function UpdateSolver(app,values)
            tdata=app.solverdata;
            R=values.R;
            tdata.R=R;
            R2=values.R2;
            tdata.R2=R2;
            loops=values.loops;
            tdata.maxloops=loops;
            tol=values.tol;
            tdata.tolmax=tol;            
            app.solverdata=tdata;
            M=values.M;
            app.Mworkers=M;
        end
        
        function UpdatePSD(app,marray)
           app.PSDarray=marray;
           if ~isempty(marray)
               app.flagInputPSD=1;
            end
        end
        
        
        function UpdateRVT(app,marray)
            if isempty(marray)
                app.ShowButton_TF.Enable='off';
                app.ShowButton_RSA.Enable='off';
            else
                nU=size(marray,2)-1;
                app.nacc=nU;
                for hh=1:nU
                    app.SRAoutput(hh).Afft=marray(:,hh+1);
                    app.SRAoutput(hh).t=marray(:,1);
                    app.SRAoutput(hh).omega=app.SRAoutput(1).omega;
                    app.SRAoutput(hh).TF=app.SRAoutput(1).TF;
                    app.SRAoutput(hh).z=app.SRAoutput(1).z;
                end
            end
        end
end
    
    
    methods (Access = private)
        
        function SaveSettings(app)
            directoryname=app.projectpath;
            matf=fullfile(directoryname, 'solversettings.mat');
            sdata=app.solverdata;
            ndata=app.NLcurve;
            save(matf,'sdata','ndata','-mat');
        end
        
          function UpdateFigure(app,t,acc,omega,H)
            hPlotData = plot(app.UIAxes,t,acc./9.81,'LineWidth',0.5);
%             [~,indx]=max(abs(acc));
%             datatip(hPlotData,"DataIndex",indx);
            hPlotData2=plot(app.UIAxes_2,omega./2/pi,abs(H),'LineWidth',0.5);
%             [~,indx]=max(abs(H));
%             app.UIAxes_2.XLim=[-max(omega)./2/pi,max(omega)./2/pi];
%             datatip(hPlotData2,"DataIndex",indx);
          end
          
          function UpdateFigureTF(app,omega,H)
            hPlotData2=plot(app.UIAxes_3,omega./2/pi,abs(H),'LineWidth',0.5);
%             [~,indx]=max(abs(H));
            app.UIAxes_3.XLim=[0,max(omega)./2/pi];
%             datatip(hPlotData2,"DataIndex",indx);
            hPlotData3=plot(app.UIAxes_4,omega./2/pi,angle(H)./pi*180  ,'LineWidth',0.5);
%             [~,indx]=max(angle(H)./pi*180);
            app.UIAxes_4.XLim=[0,max(omega)./2/pi];
%             datatip(hPlotData3,"DataIndex",indx);
            
          end  
        
        function UpdateFigurePSD(app,PSD,freq)
            hPlotData = plot(app.UIAxes2,freq,PSD,'LineWidth',0.5);
%             [~,indx]=max(PSD);
%             datatip(hPlotData,"DataIndex",indx);
            app.clPSD=max(freq);
            app.ZoomSlider.Value=0;
            app.ZoomSlider.Enable='on';
        end
        
        function LEQCOVSER(app,valueOut)
            na=app.nacc;
            %progress bar
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Solver',...
            'Message','Progression','Cancelable','on');
             drawnow
            for hh=1:na
                if d.CancelRequested
                    break
                end
             % Update progress, report current estimate
             d.Value = hh/na;
             d.Message = sprintf('%d',hh);   
             [Afft,t,H,omega,z]=LinEqConvTH(app.vz,valueOut,app.flagWave,app.G,app.Ro,app.Csi,app.NLcurve,app.Mat,app.THarray(:,hh+1),app.THarray(:,1));
             app.SRAoutput(hh)=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
            end
            % Close the dialog box
            close(d);
            close(fig);
        end
        
        function LEQCOVSERstr(app,valueOut)
            na=app.nacc;
            %progress bar
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Solver',...
            'Message','Progression','Cancelable','on');
             drawnow
            for hh=1:na
                if d.CancelRequested
                    break
                end
             % Update progress, report current estimate
             d.Value = hh/na;
             d.Message = sprintf('%d',hh);   
             [Afft,t,H,omega,z]=LinEqConvTH(app.vz,valueOut,app.flagWave,app.G,app.Ro,app.Csi,app.NLcurve,app.Mat,app.THarray(hh).acc,app.THarray(hh).t);
             app.SRAoutput(hh)=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
            end
            % Close the dialog box
            close(d);
            close(fig);
        end
        
        function LEQCOVPAR(app,valueOut)
            na=app.nacc;
            %waitbar
            D = parallel.pool.DataQueue;
            h = waitbar(0, 'Solving ...');
            afterEach(D, @nUpdateWaitbar);
            p = 1;
            vzp=app.vz;
            Gp=app.G;
            Rop=app.Ro;
            cp=app.Csi;
            THi=app.THarray;
            nlc=app.NLcurve;
            mty=app.Mat;
            ti=THi(:,1);
            fw=app.flagWave;
            parfor(hh=1:na,app.Mworkers)
            [Afft,t,H,omega,z]=LinEqConvTH(vzp,valueOut,fw,Gp,Rop,cp,nlc,mty,THi(:,hh+1),ti);
            output(hh)=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
            send(D, hh);
            end
            app.SRAoutput=output;
            close(h);
            function nUpdateWaitbar(~)
                waitbar(p/na, h);
                p = p + 1;
            end
            
        end
        
        function LPSDSER(app,valueOut)
            PSDbed=app.PSDarray(:,2);
            omega=app.PSDarray(:,1);
             [H,PSDout,z]=LinConvPSD(app.vz,valueOut,app.flagWave,app.G,app.Ro,app.Csi,PSDbed,omega);
             app.PSDoutput=struct('freq',omega./2./pi,'PSD',PSDout);
             app.SRAoutput=struct('Afft',[],'t',[],'TF',H,'omega',omega,'z',z);
        end
        
        
        function LEQCOVPARstr(app,valueOut)
            na=app.nacc;
            %waitbar
            D = parallel.pool.DataQueue;
            h = waitbar(0, 'Solving ...');
            afterEach(D, @nUpdateWaitbar);
            p = 1;
            vzp=app.vz;
            Gp=app.G;
            Rop=app.Ro;
            cp=app.Csi;
            THi=app.THarray;
            nlc=app.NLcurve;
            mty=app.Mat;
            fw=app.flagWave;
            parfor(hh=1:na,app.Mworkers)
            [Afft,t,H,omega,z]=LinEqConvTH(vzp,valueOut,fw,Gp,Rop,cp,nlc,mty,THi(hh).acc,THi(hh).t);
            output(hh)=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
            send(D, hh);
            end
            app.SRAoutput=output;
            close(h);    
            function nUpdateWaitbar(~)
                waitbar(p/na, h);
                p = p + 1;
            end
        end
        function LCOVSER(app,valueOut)
            na=app.nacc;
            %progress bar
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Solver',...
            'Message','Progression','Cancelable','on');
             drawnow
            for hh=1:na
                if d.CancelRequested
                    break
                end
             % Update progress, report current estimate
             d.Value = hh/na;
             d.Message = sprintf('%d',hh);   
             [Afft,t,H,omega,z]=LinConvTH(app.vz,valueOut,app.flagWave,app.G,app.Ro,app.Csi,app.THarray(:,hh+1),app.THarray(:,1));
             app.SRAoutput(hh)=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
            end
            % Close the dialog box
            close(d);
            close(fig);
        end
        
        function LCOVSERstr(app,valueOut)
            na=app.nacc;
            %progress bar
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Solver',...
            'Message','Progression','Cancelable','on');
             drawnow
            for hh=1:na
                if d.CancelRequested
                    break
                end
             % Update progress, report current estimate
             d.Value = hh/na;
             d.Message = sprintf('%d',hh);   
             [Afft,t,H,omega,z]=LinConvTH(app.vz,valueOut,app.flagWave,app.G,app.Ro,app.Csi,app.THarray(hh).acc,app.THarray(hh).t);
             app.SRAoutput(hh)=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
            end
            % Close the dialog box
            close(d);
            close(fig);
        end
        
        function LCOVPAR(app,valueOut)
            na=app.nacc;
            %waitbar
            D = parallel.pool.DataQueue;
            h = waitbar(0, 'Solving ...');
            afterEach(D, @nUpdateWaitbar);
            p = 1;
            vzp=app.vz;
            Gp=app.G;
            Rop=app.Ro;
            cp=app.Csi;
            THi=app.THarray;
            ti=THi(:,1);
            fw=app.flagWave;
            parfor(hh=1:na,app.Mworkers)
            [Afft,t,H,omega,z]=LinConvTH(vzp,valueOut,fw,Gp,Rop,cp,THi(:,hh+1),ti);
            output(hh)=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
            send(D, hh);
            end
            app.SRAoutput=output;
            close(h);    
            function nUpdateWaitbar(~)
                waitbar(p/na, h);
                p = p + 1;
            end
        end
        function LCOVPARstr(app,valueOut)
            na=app.nacc;
            %waitbar
            D = parallel.pool.DataQueue;
            h = waitbar(0, 'Solving ...');
            afterEach(D, @nUpdateWaitbar);
            p = 1;
            vzp=app.vz;
            Gp=app.G;
            Rop=app.Ro;
            cp=app.Csi;
            THi=app.THarray;
            fw=app.flagWave;
            parfor(hh=1:na,app.Mworkers)
            [Afft,t,H,omega,z]=LinConvTH(vzp,valueOut,fw,Gp,Rop,cp,THi(hh).acc,THi(hh).t);
            output(hh)=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
            send(D, hh);
            end
            app.SRAoutput=output;
            close(h);     
            function nUpdateWaitbar(~)
                waitbar(p/na, h);
                p = p + 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %initialization
            app.NLcurve= struct();
            app.projectpath='';
            app.THarray=[];
            app.PSDarray=[];
            app.RunButton.Enable = 'off';
            app.StratigraphyMenu.Enable='off';
            app.SeismicInputMenu.Enable='off';
            app.SoilUncertaintyMenu.Enable='off';
            app.AdvancedMenu.Enable='on';
            app.PresaichFormalismMenu.Enable='off';
            app.SourceofUncertaintyPanel.Enable='off';
            app.CheckButton.Enable='on';
            app.StratigrafyLamp.Color=[0.80,0.80,0.80];
            app.SeismicInputLamp.Color=[0.80,0.80,0.80];
            app.StochasticInputLamp.Color=[0.80,0.80,0.80];
            %
            app.AnalysisTypeKnob.Value='Off';
            poolobj = gcp('nocreate');
                 if isempty(poolobj)
                     app.ParallelComputingLamp.Color=[0.80,0.80,0.80];
                     app.ActivateParallelComputingSwitch.Value='Off';
                     app.flagPar=0;
                 else
                     app.ParallelComputingLamp.Color=[1.0 1.0 0.0];
                     app.ActivateParallelComputingSwitch.Value='On';
                     app.flagPar=1;
                 end
            %set flags
            app.flagInput=0;
            app.flagInputPSD=0;
            app.flagSoil=0;
            app.flagOut=0;
            app.flagWave=0;
            app.flagLin=0;
            app.flagTH=0;
            app.flagNL=0;
            %settings
            app.solverdata=struct('R',0.65,'R2',1,'maxloops',50,'polin',3,'tolmax',0.0001,'zeropad',500);
            app.Mworkers=4;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Disable Run Options button while dialog is open
            app.RunButton.Enable = 'off';
            %save solver settings
            SaveSettings(app);
            %read options switches
            if app.flagOut == 0
                valueOut='outcrop';
            else
                valueOut='inside';
            end
            %test 
            app.SRAoutput=struct('Afft',[],'t',[],'TF',[],'omega',[],'z',[]);
            %selection
            value = app.AnalysisTypeKnob.Value;
            switch value
                case 'Off'
                    app.RunButton.Enable = 'off';
                case 'Deterministic'
                    value = app.SoilPropertiesSwitch.Value;
                    switch value
                        case 'Equivalent Linear'
                                        %progress bar
                                fig = uifigure;
                                d = uiprogressdlg(fig,'Title','Solver',...
                            'Message','Progression','Cancelable','on');
                                drawnow
                                if app.flagTH == 0
                                    [Afft,t,H,omega,z]=LinEqConvTH(app.vz,valueOut,app.flagWave,app.G,app.Ro,app.Csi,app.NLcurve,app.Mat,app.THarray(:,2),app.THarray(:,1));
                                    app.SRAoutput=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
                                else
                                    [Afft,t,H,omega,z]=LinEqConvTH(app.vz,valueOut,app.flagWave,app.G,app.Ro,app.Csi,app.NLcurve,app.Mat,app.THarray(1).acc,app.THarray(1).t);
                                    app.SRAoutput=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
                                end 
                                % Close the dialog box
                                close(d)
                                close(fig)
                        case 'Linear'
                                if app.flagTH == 0
                                    [Afft,t,H,omega,z]=LinConvTH(app.vz,valueOut,app.flagWave,app.G,app.Ro,app.Csi,app.THarray(:,2),app.THarray(:,1));
                                    app.SRAoutput=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
                                else
                                    [Afft,t,H,omega,z]=LinConvTH(app.vz,valueOut,app.flagWave,app.G,app.Ro,app.Csi,app.THarray(1).acc,app.THarray(1).t);
                                    app.SRAoutput=struct('Afft',Afft.','t',t,'TF',H,'omega',omega,'z',z);
                                end 
                    end  
                case 'MCS'
                    value = app.SoilPropertiesSwitch.Value;
                    switch value
                        case 'Equivalent Linear'
                            if app.flagTH == 0
                                    if app.flagPar==0
                                        LEQCOVSER(app,valueOut);
                                    else
                                        LEQCOVPAR(app,valueOut)
                                    end
                            else
                                    if app.flagPar==0
                                        LEQCOVSERstr(app,valueOut);
                                    else
                                        LEQCOVPARstr(app,valueOut)
                                    end
                            end
                        case 'Linear'
                                if app.flagTH == 0
                                    if app.flagPar==0
                                        LCOVSER(app,valueOut);
                                    else
                                        LCOVPAR(app,valueOut)
                                    end
                                else
                                    if app.flagPar==0
                                        LCOVSERstr(app,valueOut);
                                    else
                                        LCOVPARstr(app,valueOut)
                                    end
                                end
                    end                    
                case 'RVT'
                    value = app.SoilPropertiesSwitch.Value;
                    switch value
                        case 'Equivalent Linear'
                            LPSDSER(app,valueOut);
                            app.nacc=0;
                        case 'Linear'
                            LPSDSER(app,valueOut);
                            app.nacc=0;
                    end
                    app.StochasticfromPSDButton.Enable='on';
                case 'Fuzzy+RVT'
                otherwise
            end
            app.RunButton.Enable = 'on';
            app.RightPanel.Enable= 'on';
            if app.nacc == 1
                app.accSpinner.Enable='off';
                app.fromaccSpinner.Enable='off';
                app.toaccSpinner.Enable='off';
            elseif app.nacc>1
                app.accSpinner.Limits=[1,app.nacc];
                app.fromaccSpinner.Limits=[1,app.nacc];
                app.toaccSpinner.Limits=[1,app.nacc];
            else
                app.SelectTimeHistorySamplesButtonGroup.Enable='off';
            end
            app.SoilDepositSpinner.Enable='off';
        end

        % Menu selected function: SoilProfileMenu
        function SoilProfileMenuSelected(app, event)

            % Open the options dialog and pass inputs
            app.SoilProfileDialog = SoilProfile(app);          
        end

        % Close request function: 
        % USRAUncertaintySiteResponseAnalysisUIFigure
        function USRAUncertaintySiteResponseAnalysisUIFigureCloseRequest(app, event)
            fclose('all');
            if exist('SoilProfile.sra', 'file')==2
                delete('SoilProfile.sra');
            end
            delete(app.SoilProfileDialog)
            if exist('Bedrock.sra', 'file')==2
                delete('Bedrock.sra');
            end
            if exist('solversettings.mat',"file")==2
                delete('solversettings.mat');
            end
            delete(app)
            
        end

        % Menu selected function: CyclicParametersMenu
        function CyclicParametersMenuSelected(app, event)
             % Open the options dialog and pass inputs
             marray=app.NLcurve;
            app.NLcurvesDialog=NLcurves(app,marray);
        end

        % Value changed function: SoilPropertiesSwitch
        function SoilPropertiesSwitchValueChanged(app, event)
            value = app.SoilPropertiesSwitch.Value;
            switch value
                case 'Equivalent Linear'
                    app.flagLin=1;
                    app.CyclicParametersMenu.Enable='on';
                case 'Linear'
                    app.CyclicParametersMenu.Enable='off';  
                    app.NLcurve= struct();
                    app.flagLin=0;
            end
            app.RunButton.Enable='off';
        end

        % Menu selected function: TimeHistoryFunctionsMenu
        function TimeHistoryFunctionsMenuSelected(app, event)
            marray=app.THarray;
            app.THImportDialog = THimport(app,marray); 
        end

        % Menu selected function: SignalProcessingMenu
        function SignalProcessingMenuSelected(app, event)
            % Open the options dialog and pass inputs
            app.SignalProcDialog = SignalProc(app);   
        end

        % Menu selected function: SolverSettingsMenu
        function SolverSettingsMenuSelected(app, event)
            marray=app.solverdata;
            app.SolverSettingDialog=SolverSet(app,marray);
        end

        % Value changed function: AnalysisTypeKnob
        function AnalysisTypeKnobValueChanged(app, event)
            value = app.AnalysisTypeKnob.Value;
            switch value
                case 'Off'
                    app.StratigraphyMenu.Enable='off';
                    app.SeismicInputMenu.Enable='off';
                    app.SoilUncertaintyMenu.Enable='off';
                    app.StratigrafyLamp.Color=[0.80,0.80,0.80];
                    app.SeismicInputLamp.Color=[0.80,0.80,0.80];
                    app.StochasticInputLamp.Color=[0.80,0.80,0.80];
                case 'Deterministic'
                    app.StratigraphyMenu.Enable='on';
                    app.SeismicInputMenu.Enable='on';
                    app.SoilUncertaintyMenu.Enable='off';
                    app.PowerSpectralDensityMenu.Enable='off';
                    app.TimeHistoryFunctionsMenu.Enable='on';
                    app.GenerateTHsMenu.Enable='off';
                    app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                    app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                    app.StochasticInputLamp.Color=[0.80,0.80,0.80];
                case 'MCS'
                    app.StratigraphyMenu.Enable='on';
                    app.SeismicInputMenu.Enable='on';
                    app.SoilUncertaintyMenu.Enable='off';
                    app.PowerSpectralDensityMenu.Enable='on';
                    app.TimeHistoryFunctionsMenu.Enable='on';
                    app.GenerateTHsMenu.Enable='on';
                    app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                    app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                    app.StochasticInputLamp.Color=[1.00,0.00,0.00];
                case 'RVT'
                    app.StratigraphyMenu.Enable='on';
                    app.SeismicInputMenu.Enable='on';
                    app.TimeHistoryFunctionsMenu.Enable='off';
                    app.SoilUncertaintyMenu.Enable='off';
                    app.PowerSpectralDensityMenu.Enable='on';
                    app.GenerateTHsMenu.Enable='off';
                    app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                    app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                    app.StochasticInputLamp.Color=[1.00,0.00,0.00];
                case 'Fuzzy+RVT'
                    app.StratigraphyMenu.Enable='on';
                    app.SeismicInputMenu.Enable='on';
                    app.TimeHistoryFunctionsMenu.Enable='off';
                    app.SoilUncertaintyMenu.Enable='on';
                    app.PowerSpectralDensityMenu.Enable='on';
                    app.GenerateTHsMenu.Enable='off';
                    app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                    app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                    app.StochasticInputLamp.Color=[1.00,0.00,0.00];
                otherwise
                    app.StratigraphyMenu.Enable='off';
                    app.SeismicInputMenu.Enable='off';
                    app.SoilUncertaintyMenu.Enable='off';
            end
            app.RunButton.Enable='off';
        end

        % Value changed function: InputPositionSwitch_2
        function InputPositionSwitch_2ValueChanged(app, event)
            value = app.InputPositionSwitch_2.Value;
            switch value
                case 'Outcrop'
                app.flagOut=0;
                case 'Bedrock'
                app.flagOut=1;
            end
            app.RunButton.Enable='off';
        end

        % Value changed function: WavePropagationSwitch
        function WavePropagationSwitchValueChanged(app, event)
            value = app.WavePropagationSwitch.Value;
            switch value
                case 'Convolution'
                app.flagWave=0;
                case 'Deconvolution'
                app.flagWave=1;
            end
            app.RunButton.Enable='off';
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
                       value = app.AnalysisTypeKnob.Value;
            switch value
                case 'Off'
                    app.RunButton.Enable='off';
                case 'Deterministic'
                    if app.flagLin ==0
                        if (app.flagInput & app.flagSoil)==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.80,0.80,0.80];
                        app.RunButton.Enable='on';
                        elseif app.flagInput==1 && app.flagSoil==0
                        app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.80,0.80,0.80];
                        elseif app.flagInput==0 && app.flagSoil==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                        app.StochasticInputLamp.Color=[0.80,0.80,0.80];                     
                        end
                    else
                        if (app.flagInput & app.flagSoil &app.flagNL)==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.80,0.80,0.80];
                        app.RunButton.Enable='on';
                        elseif app.flagInput==1 && and(app.flagSoil,app.flagNL)==0
                        app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.80,0.80,0.80];
                        elseif app.flagInput==0 && and(app.flagSoil,app.flagNL)==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                        app.StochasticInputLamp.Color=[0.80,0.80,0.80];                     
                        end
                    end
                case 'MCS'
                    na=app.nacc;
                    if na>1
                    if app.flagLin ==0
                        if (app.flagInput & app.flagSoil)==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.00,1.0,0.0];
                        app.RunButton.Enable='on';
                        elseif app.flagInput==1 && app.flagSoil==0
                        app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.00,1.00,0.0];
                        elseif app.flagInput==0 && app.flagSoil==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                        app.StochasticInputLamp.Color=[1.0,0.0,0.0];                     
                        end
                    else
                        if (app.flagInput & app.flagSoil &app.flagNL)==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.00,1.00,0.00];
                        app.RunButton.Enable='on';
                        elseif app.flagInput==1 && and(app.flagSoil,app.flagNL)==0
                        app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.00,1.00,0.00];
                        elseif app.flagInput==0 && and(app.flagSoil,app.flagNL)==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                        app.StochasticInputLamp.Color=[1.00,0.00,0.0];                     
                        end
                    end
                    elseif na==1
                        fig = uifigure;
                        uialert(fig,'Number of Accelerograms must be greater than 1','No enough data for MCS','Icon','error');
                    else
                        fig = uifigure;
                        uialert(fig,'Stochastic Input not defined','Incorrect Stochastic input data','Icon','warning');
                    end
                case 'RVT'
                    if app.flagLin ==0
                        if (app.flagInputPSD & app.flagSoil)==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.00,1.0,0.0];
                        app.RunButton.Enable='on';
                        elseif app.flagInputPSD==1 && app.flagSoil==0
                        app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.00,1.00,0.0];
                        elseif app.flagInputPSD==0 && app.flagSoil==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                        app.StochasticInputLamp.Color=[1.0,0.0,0.0];                     
                        end
                    else
                        if (app.flagInputPSD & app.flagSoil &app.flagNL)==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.00,1.00,0.00];
                        app.RunButton.Enable='on';
                        elseif app.flagInputPSD==1 && and(app.flagSoil,app.flagNL)==0
                        app.StratigrafyLamp.Color=[1.00,0.00,0.00];
                        app.SeismicInputLamp.Color=[0.00,1.00,0.00];
                        app.StochasticInputLamp.Color=[0.00,1.00,0.00];
                        elseif app.flagflagInputPSDInput==0 && and(app.flagSoil,app.flagNL)==1
                        app.StratigrafyLamp.Color=[0.00,1.00,0.00];
                        app.SeismicInputLamp.Color=[1.00,0.00,0.00];
                        app.StochasticInputLamp.Color=[1.00,0.00,0.0];                     
                        end
                    end
                case 'Fuzzy+RVT'

                otherwise

            end
        end

        % Value changed function: DepthmEditField
        function DepthmEditFieldValueChanged(app, event)
            value = app.DepthmEditField.Value;
            z=app.SRAoutput.z;
            [~,indz]=min(abs(z-value));
            app.DepthmEditField.Value=z(indz);
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
             if app.HoldButton.Value==0
                hold(app.UIAxes,"off");
                hold(app.UIAxes_2,"off");
            elseif app.HoldButton.Value==1
                hold(app.UIAxes,"on");
                hold(app.UIAxes_2,"on"); 
             end
            na=app.nacc;
            if na ==0
                depth=app.DepthmEditField.Value;
                THi=app.SRAoutput;
                zi=THi.z;
                slctS=app.SurfaceButton.Value;
                slctB=app.BedrockButton.Value;
                [~,indz]=min(abs(zi-depth));
                PSD=app.PSDoutput.PSD;
                freq=app.PSDoutput.freq;
                if slctS==1
                    PSDi=struct('PSD',PSD(1,:),'freq',freq);
                elseif slctB==1
                    PSDi=struct('PSD',PSD(end,:),'freq',freq);
                else
                    PSDi=struct('PSD',PSD(indz,:),'freq',freq);
                end
                app.RVTgenDialog=RVTgen(app,PSDi);
                waitfor(app.RVTgenDialog);
                app.SelectTimeHistorySamplesButtonGroup.Enable='on';
            end
            app.THoutput=struct('t',[],'acc',[]);
            if app.SingleButton.Value == 1
                ind1=app.accSpinner.Value;
                ind2=ind1;
            elseif app.RangeButton.Value ==1
                ind1=app.fromaccSpinner.Value;
                ind2=app.toaccSpinner.Value;
            else
                ind1=1;
                ind2=na;
            end
            depth=app.DepthmEditField.Value;
            oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
            set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
            drawnow;
            THi=app.SRAoutput;
            slctS=app.SurfaceButton.Value;
            slctB=app.BedrockButton.Value;
            for hh=ind1:ind2
                 ti=THi(hh).t;
                 zi=THi(hh).z;
                 [~,indz]=min(abs(zi-depth));
            if slctS==1
                acci=THi(hh).Afft(:,1);
            elseif slctB==1
                acci=THi(hh).Afft(:,end);
            else
                acci=THi(hh).Afft(:,indz);
            end
            [fomegai,omegai]=FFTcompl(acci,ti);
            app.THoutput(hh)=struct('t',ti,'acc',acci);
            UpdateFigure(app,ti,acci,omegai,fomegai);
            hold(app.UIAxes,"on");
            hold(app.UIAxes_2,"on");
            end
            hold(app.UIAxes,"off");
            hold(app.UIAxes_2,"off");
            set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer) 
        end

        % Button pushed function: ShowButton_PSD
        function ShowButton_PSDPushed(app, event)
            if app.HoldButton_2.Value==0
                hold(app.UIAxes2,"off");
            elseif app.HoldButton_2.Value==1
                hold(app.UIAxes2,"on");
            end
            na=app.nacc;
            depth=app.DepthmEditField.Value;
            if app.SingleButton.Value == 1
                ind1=app.accSpinner.Value;
                ind2=ind1;
            elseif app.RangeButton.Value ==1
                ind1=app.fromaccSpinner.Value;
                ind2=app.toaccSpinner.Value;
            else
                ind1=1;
                ind2=na;
            end
            if app.flagInputPSD==0
                app.PSDoutput=struct('freq',[],'PSD', []);
                oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
                set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
                drawnow;
                THi=app.SRAoutput;
                slctS=app.SurfaceButton.Value;
                slctB=app.BedrockButton.Value;
                for hh=ind1:ind2
                     ti=THi(hh).t;
                     zi=THi(hh).z;
                     [~,indz]=min(abs(zi-depth));
                if slctS==1
                    column=1;
                elseif slctB==1
                    column=length(zi);
                else
                    column=indz;
                end
                [PSDi,freqi]=acc2PSD([ti THi(hh).Afft(:,column)]);
                app.PSDoutput(hh)=struct('freq',freqi.','PSD',PSDi);
                UpdateFigurePSD(app,PSDi,freqi);
                hold(app.UIAxes2,"on");
                end
                set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer);
            else
                THi=app.SRAoutput;
                slctS=app.SurfaceButton.Value;
                slctB=app.BedrockButton.Value;
                for hh=ind1:ind2
                     zi=THi.z;
                     [~,indz]=min(abs(zi-depth));
                if slctS==1
                    column=1;
                elseif slctB==1
                    column=length(zi);
                else
                    column=indz;
                end
                PSDi=app.PSDoutput.PSD;
                freqi=app.PSDoutput.freq;
                UpdateFigurePSD(app,PSDi(column,:).',freqi);
                end
            end
            hold(app.UIAxes2,"off");
            
        end

        % Button pushed function: ShowButton_TF
        function ShowButton_TFPushed(app, event)
             if app.HoldButton_3.Value==0
                hold(app.UIAxes_3,"off");
                hold(app.UIAxes_4,"off");
            elseif app.HoldButton_3.Value==1
                hold(app.UIAxes_3,"on");
                hold(app.UIAxes_4,"on"); 
             end
            na=app.nacc;
            depth=app.DepthmEditField.Value;
            if app.SingleButton.Value == 1
                ind1=app.accSpinner.Value;
                ind2=ind1;
            elseif app.RangeButton.Value ==1
                ind1=app.fromaccSpinner.Value;
                ind2=app.toaccSpinner.Value;
            else
                ind1=1;
                ind2=na;
            end
            for hh=ind1:ind2
                THi=app.SRAoutput(hh);
                omega=THi.omega;
                z=THi.z;
                [~,indz]=min(abs(z-depth));
                app.DepthmEditField.Value=z(indz);
                if app.SurfaceButton.Value==1
                    column=1;
                elseif app.BedrockButton.Value==1
                    column=length(z);
                else
                    column=indz;
                end
                H=THi.TF(column,:);
                UpdateFigureTF(app,omega,H);
                hold(app.UIAxes_3,"on");
                hold(app.UIAxes_4,"on");
            end
                hold(app.UIAxes_3,"off");
                hold(app.UIAxes_4,"off");
        end

        % Button pushed function: ShowButton_RSA
        function ShowButton_RSAPushed(app, event)
            
            if app.HoldButton_4.Value==0
                hold(app.UIAxes2_2,"off");
            elseif app.HoldButton_4.Value==1
                hold(app.UIAxes2_2,"on"); 
            end
            na=app.nacc;
            Sat=0;
            if app.SingleButton.Value == 1
                ind1=app.accSpinner.Value;
                ind2=ind1;
            elseif app.RangeButton.Value ==1
                ind1=app.fromaccSpinner.Value;
                ind2=app.toaccSpinner.Value;
            else
                ind1=1;
                ind2=na;
            end
            depth=app.DepthmEditField.Value;
            MM=app.SRAoutput;
            slctS=app.SurfaceButton.Value;
            slctB=app.BedrockButton.Value;
            oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
            set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
            drawnow;
            if app.flagPar==1
            parfor(hh=ind1:ind2,app.Mworkers)
                THi=MM(hh);
                t=THi.t;
                dt=t(2)-t(1);
                 z=THi.z;
                 [~,indz]=min(abs(z-depth));
%                  app.DepthmEditField.Value=z(indz);
            if slctS==1
                acc=THi.Afft(:,1);
            elseif slctB==1
                acc=THi.Afft(:,end);
            else
                acc=THi.Afft(:,indz);
            end
            [Sai,Ti] = resp_spectr_MEXP(acc,dt,100,0.05);
            Sat=Sat+Sai;
            Sa(:,hh)=Sai;
            T(:,hh)=Ti;
            end
            else
            for hh=ind1:ind2
                THi=MM(hh);
                t=THi.t;
                dt=t(2)-t(1);
                 z=THi.z;
                 [~,indz]=min(abs(z-depth));
%                  app.DepthmEditField.Value=z(indz);
            if slctS==1
                acc=THi.Afft(:,1);
            elseif slctB==1
                acc=THi.Afft(:,end);
            else
                acc=THi.Afft(:,indz);
            end
            [Sai,Ti] = resp_spectr_MEXP(acc,dt,100,0.05);
            Sat=Sat+Sai;
            Sa(:,hh)=Sai;
            T(:,hh)=Ti;
            end
            end
            if app.IndividualButton.Value==1
            plot(app.UIAxes2_2,T(:,1),Sa/9.81);
            end
            if app.AverageButton.Value==1
                hold(app.UIAxes2_2,"on");
                ns=ind2-ind1+1;
                Sam=sum(Sat,1)/ns;
                plot(app.UIAxes2_2,T(:,1),Sam/9.81,'LineWidth',2,"Color",'r')
            end
            hold(app.UIAxes2_2,"off");
            set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer)
        end

        % Button pushed function: ShowButton_5
        function ShowButton_5Pushed(app, event)
            if app.HoldButton_5.Value==0
                hold(app.UIAxes3_2,"off");
                hold(app.UIAxes3,"off");
            elseif app.HoldButton_5.Value==1
                hold(app.UIAxes3_2,"on"); 
                hold(app.UIAxes3,"on"); 
            end
            t=app.SRAoutput.t;
            if app.StationarytimewindowsEditField.Value == 0
            Ts=t(end);
            app.StationarytimewindowsEditField.Value = Ts;
            end
            Ts = app.StationarytimewindowsEditField.Value;
            if app.StatisticalfromTHButton.Value==1
                app.ProbabilityzerocrossingPanel.Enable='off';
                nc=numel((app.THoutput));
                ind=1;
                for hh=1:nc
                THi=app.THoutput(hh).acc;
                tmp=max(abs(THi));
                    if ~isempty(tmp)
                        pga(ind)=tmp;
                        ind=ind+1;
                    end
                end
                [f,x] = ecdf(pga./9.81);
                stairs(app.UIAxes3,x,f);
                histogram(app.UIAxes3_2,pga./9.81,'Normalization','probability');
            else
                p=0.01:0.01:0.99;
                nc=numel((app.PSDoutput));
                ind=1;
                for hh=1:nc
                PSD=app.PSDoutput(hh).PSD;
                freq=app.PSDoutput(hh).freq;
                if isempty(PSD)
                    [tpm,~,lb0]=peakmean(PSD,freq*2*pi,p,Ts);
                    pmi(:,ind)=tpm;
                    ind=ind+1;
                end
                end
                pm=mean(pmi,1);
                [f,x] = ecdf(pm./9.81);
                stairs(app.UIAxes3,x,f);
                histogram(app.UIAxes3_2,pm./9.81,'Normalization','probability');
                app.ZerothorderspectralmomentEditField.Value=lb0;
                app.MedianvalueofthepeakaccelerationEditField.Value=x(find(round(f,2)==0.5,1));  
                hold(app.UIAxes3_2,"off");
                hold(app.UIAxes3,"off");
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            plot(app.UIAxes,0,0);
            plot(app.UIAxes_2,0,0);
        end

        % Button pushed function: ClearButton_4
        function ClearButton_4Pushed(app, event)
            plot(app.UIAxes_3,0,0);
            plot(app.UIAxes_4,0,0);
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            plot(app.UIAxes2,0,0);
        end

        % Button pushed function: ClearButton_3
        function ClearButton_3Pushed(app, event)
            plot(app.UIAxes2_2,0,0);
        end

        % Button pushed function: ClearButton_5
        function ClearButton_5Pushed(app, event)
            plot(app.UIAxes3,0,0);
            plot(app.UIAxes3_2,0,0);
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            zoom = event.Value;
            app.UIAxes2.XLim=[0,app.clPSD*(1-zoom/100)];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            EN=app.ComparewithDropDown.Value;
            switch EN
                case 'EN1998 GrType A'
                    soiltype='A';
                case 'EN1998 GrType B'
                    soiltype='B';
                case 'EN1998 GrType C'
                    soiltype='C';
                case 'EN1998 GrType D'
                    soiltype='D';
                otherwise
                    soiltype='A';
            end
            ag=app.aggEditField.Value;
            [RSA,T]=EN8RSA(ag,soiltype);
            hold(app.UIAxes2_2,"on");
            plot(app.UIAxes2_2,T,RSA);
            hold(app.UIAxes2_2,"off");
        end

        % Callback function
        function StationarytimewindowsEditFieldValueChanged(app, event)
            Ts = app.StationarytimewindowsEditField.Value;
            p=0.01:0.01:0.99;
            t=app.SRAoutput.t;
            z=app.SRAoutput.z;
            na=app.nacc;
            depth=app.DepthmEditField.Value;
            [~,indz]=min(abs(z-depth));
            app.DepthmEditField.Value=z(indz);
            if app.SurfaceButton.Value==1
                column=1;
            elseif app.BedrockButton.Value==1
                column=length(z);
            else
                column=indz;
            end
            if app.RangeButton.Value==1
                THi=app.SRAoutput(1:na);
                [PSD,freq]=acc2PSD([t THi.Afft(:,column)]);
                [pm,~,lb0]=peakmean(PSD,freq.'*2*pi,p,Ts);
                [f,x] = ecdf(pm./9.81);
                stairs(app.UIAxes3,x,f);
                app.ZerothorderspectralmomentEditField.Value=lb0;
                app.MedianvalueofthepeakaccelerationEditField.Value=pm(50)./9.81;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case app.DataDistributionTab.Title
                    app.SelectTimeHistorySamplesButtonGroup.Enable='off';
                    app.SelectPositionButtonGroup.Enable='off';
                    app.ShowButton_5.Enable='off';
                    if ~isempty((app.THoutput))
                        app.StatisticalfromTHButton.Enable='on';
                        app.ShowButton_5.Enable='on';
                    else
                        app.StatisticalfromTHButton.Enable='off';
                    end
                    if ~isempty((app.PSDoutput))
                        app.ProbabilityfromPSDButton.Enable='on';
                        app.ProbabilityfromPSDButton.Value=1;
                        app.ProbabilityzerocrossingPanel.Enable='on';
                        app.ShowButton_5.Enable='on';
                    else
                        app.ProbabilityfromPSDButton.Enable='off';
                        app.StatisticalfromTHButton.Value=1;
                        app.ProbabilityzerocrossingPanel.Enable='off';
                    end
                case app.TransferFunctionTab.Title
                    app.SelectTimeHistorySamplesButtonGroup.Enable='on';
                    app.AllButton.Value=1;
                case app.PSDTab.Title
                    if ~isempty((app.THoutput))
                        app.SelectTimeHistorySamplesButtonGroup.Enable='on';
                    end
                    switch app.AnalysisTypeKnob.Value
                        case  'RVT'
                        app.SelectTimeHistorySamplesButtonGroup.Enable='off';
                    end
                case app.ResponseSpectrumTab.Title
                    if isempty((app.THoutput))
                        app.DeterministicfromTHsButton.Enable='off';
                        app.PlotResponseSpectrumPanel.Enable='off';
                    else
                        app.DeterministicfromTHsButton.Enable='on';
                        app.PlotResponseSpectrumPanel.Enable='on';
                    end
                    switch app.AnalysisTypeKnob.Value
                        case  'RVT'
                        app.StochasticfromPSDButton.Enable='on';
                    end
                otherwise
                    app.SelectTimeHistorySamplesButtonGroup.Enable='on';
                    app.SelectPositionButtonGroup.Enable='on';
            end
        end

        % Menu selected function: Example1Menu
        function Example1MenuSelected(app, event)
            app.AnalysisTypeKnob.Value='Deterministic';
            app.flagLin =1;
            app.flagInput=1;
            app.flagSoil=1;
            app.flagTH=0;
            app.flagOut=0;
            app.flagWave=0;
            app.flagNL=1; 
            app.flagInputPSD=0;
            app.Vs=[ones(40,1).*200; 800];
            app.vz=ones(40,1).*0.5;
            app.Ro=[ones(40,1).*1950; 2000];
            app.G=(app.Vs).^2.*(app.Ro);
            app.Mat=[ones(40,1);0];
            app.Csi=ones(41,1).*0.05;
            Xg = [0.0001 0.0003 0.001 0.003 0.01 0.03 0.1 0.3 1 3 10 100];
            Yg = [1 1 1 0.981 0.941 0.847 0.656 0.438 0.238 0.144 0.11 0.11];
            Xcsi = [0.0001 0.0003 0.001 0.003 0.01 0.03 0.1 0.3 1 3.16 10 100];
            Ycsi = [0.24 0.42 0.8 1.4 2.8 5.1 9.8 15.5 21.0 25 28 28];
            app.NLcurve(1).Xg=Xg;
            app.NLcurve(1).Yg=Yg;
            app.NLcurve(1).Xcsi=Xcsi;
            app.NLcurve(1).Ycsi=Ycsi;
            app.THarray=readmatrix('Earthquake.txt');
            app.nacc=1;
            app.StratigrafyLamp.Color=[0.00,1.00,0.00];
            app.SeismicInputLamp.Color=[0.00,1.00,0.00];
            app.StochasticInputLamp.Color=[0.80,0.80,0.80];
            app.SoilPropertiesSwitch.Value='Equivalent Linear';
            app.RunButton.Enable='on';
            app.CheckButton.Enable='off';
            app.StratigraphyMenu.Enable='off';
            app.SeismicInputMenu.Enable='off';
            app.Mworkers=1;
        end

        % Menu selected function: Example2Menu
        function Example2MenuSelected(app, event)
            app.AnalysisTypeKnob.Value='MCS';
            app.flagLin =0;
            app.flagInput=1;
            app.flagSoil=1;
            app.flagTH=0;
            app.flagOut=1;
            app.flagLin=0;
            app.flagWave=0;
            app.flagNL=0; 
            app.flagInputPSD=0;
            app.Vs=[ones(40,1).*200; 800];
            app.vz=ones(40,1).*0.5;
            app.Ro=[ones(40,1).*1950; 2000];
            app.G=(app.Vs).^2.*(app.Ro);
            app.Mat=[ones(40,1);0];
            app.Csi=ones(41,1).*0.05;
            app.NLcurve=[];
            app.THarray=load('THSpectrumC.mat').ACC;
            app.nacc=30;
            app.StratigrafyLamp.Color=[0.00,1.00,0.00];
            app.SeismicInputLamp.Color=[0.00,1.00,0.00];
            app.StochasticInputLamp.Color=[0.00,1.00,0.0];
            app.InputPositionSwitch_2.Value='Bedrock';
            app.RunButton.Enable='on';        
            app.Mworkers=4;
            app.CheckButton.Enable='off';
            app.StratigraphyMenu.Enable='off';
            app.SeismicInputMenu.Enable='off';
        end

        % Menu selected function: Example3Menu
        function Example3MenuSelected(app, event)
            app.AnalysisTypeKnob.Value='RVT';
            app.flagLin=0;
            app.flagInput=0;
            app.flagSoil=1;
            app.flagTH=0;
            app.flagOut=1;
            app.flagLin=0;
            app.flagWave=0;
            app.flagNL=0; 
            app.flagInputPSD=1;
            app.PSDarray=load('PSDfirmsoil.mat').AA;
            app.Vs=[ones(20,1).*400; 800];
            app.vz=ones(20,1).*0.5;
            app.Ro=[ones(20,1).*2000; 2000];
            app.G=(app.Vs).^2.*(app.Ro);
            app.Mat=[ones(20,1);0];
            app.Csi=ones(21,1).*0.05;
            app.NLcurve=[]; 
            app.StratigrafyLamp.Color=[0.00,1.00,0.00];
            app.SeismicInputLamp.Color=[0.00,1.00,0.00];
            app.StochasticInputLamp.Color=[0.00,1.00,0.0];
            app.InputPositionSwitch_2.Value='Bedrock';
            app.RunButton.Enable='on';        
            app.Mworkers=4;
            app.CheckButton.Enable='off';
            app.StratigraphyMenu.Enable='off';
            app.SeismicInputMenu.Enable='off';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushedTH(app, event)
            fig1=app.UIAxes.Children;
            nn=numel(fig1);
            if nn>0
            TH(nn)=struct('x',[],'y',[]);

            for hh=1:nn
               x= fig1(hh).XData.'; 
               TH(hh).x=x;
               y= fig1(hh).YData.'; 
               TH(hh).y=y;         
            end
                         [file,path,indx] = uiputfile({'*.mat','MAT-Files (*.mat)';
   '*.txt','Text File (*.txt)';
   '*.csv','Text File (*.csv)';
   '*.xlsx','Worksheets (*.xlsx)'}, ...
   'Select a File');
             filename = fullfile(path,file);
             switch indx
                case 1
                    save(filename,'TH','-mat');
                case 2
                    for hh=1:nn
                        namef=fullfile(path,strcat(file(1:end-4),num2str(hh),'.txt'));
                        fileID = fopen(namef,'w');
                        fprintf(fileID,'%6s %12s\r\n','t','acc');
                        fprintf(fileID,'%6.4f %12.8f\r\n',[transpose(TH(hh).x); transpose(TH(hh).y)]);
                        fclose(fileID);
                    end
                case 3
                     for hh=1:nn
                        writematrix([transpose(TH(hh).x); transpose(TH(hh).y)],filename,'FileType','text','WriteMode','append');
                      end
                case 4
                      oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
                      drawnow;
                     for hh=1:nn
                        writematrix([TH(hh).x TH(hh).y],filename,'FileType','spreadsheet','WriteMode','append','Sheet',hh);
                     end
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer)             
                otherwise
                    %tdata=table(0,0,0,0);
             end    
            else
                fig = uifigure;
                uialert(fig,'Empty Plot','No Data to Export','Icon','warning');
            end
            
        end

        % Button pushed function: ExportButton_6
        function ExportButton_FFTPushed(app, event)
            fig2=app.UIAxes_2.Children;
            nn=numel(fig2);
            if nn>0
            FFT(nn)=struct('x',[],'y',[]);
            for hh=1:nn
               x= fig2(hh).XData.'; 
               FFT(hh).x=x;
               y= fig2(hh).YData.'; 
               FFT(hh).y=y;         
            end
                         [file,path,indx] = uiputfile({'*.mat','MAT-Files (*.mat)';
   '*.txt','Text File (*.txt)';
   '*.csv','Text File (*.csv)';
   '*.xlsx','Worksheets (*.xlsx)'}, ...
   'Select a File');
             filename = fullfile(path,file);
             switch indx
                case 1
                    save(filename,'FFT','-mat');
                case 2
                    for hh=1:nn
                        namef=fullfile(path,strcat(file(1:end-4),num2str(hh),'.txt'));
                        fileID = fopen(namef,'w');
                        fprintf(fileID,'%6s %12s\r\n','freq','FFT');
                        fprintf(fileID,'%6.4f %12.8f\r\n',[transpose(FFT(hh).x); transpose(FFT(hh).y)]);
                    end
                case 3
                     for hh=1:nn
                        writematrix([transpose(FFT(hh).x); transpose(FFT(hh).y)],filename,'FileType','text','WriteMode','append');
                      end
                case 4
                      oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
                      drawnow;
                     for hh=1:nn
                        writematrix([FFT(hh).x FFT(hh).y],filename,'FileType','spreadsheet','WriteMode','append','Sheet',hh);
                     end
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer)             
                otherwise
                    %tdata=table(0,0,0,0);
             end
            else
                fig = uifigure;
                uialert(fig,'Empty Plot','No Data to Export','Icon','warning');
            end
        end

        % Button pushed function: ExportButton_7
        function ExportButton_HPushed(app, event)
         fig2=app.UIAxes_3.Children;
            nn=numel(fig2);
            if nn>0
            H(nn)=struct('x',[],'y',[]);
            for hh=1:nn
               x= fig2(hh).XData.'; 
               H(hh).x=x;
               y= fig2(hh).YData.'; 
               H(hh).y=y;         
            end
                         [file,path,indx] = uiputfile({'*.mat','MAT-Files (*.mat)';
   '*.txt','Text File (*.txt)';
   '*.csv','Text File (*.csv)';
   '*.xlsx','Worksheets (*.xlsx)'}, ...
   'Select a File');
             filename = fullfile(path,file);
             switch indx
                case 1
                    save(filename,'H','-mat');
                case 2
                    for hh=1:nn
                        namef=fullfile(path,strcat(file(1:end-4),num2str(hh),'.txt'));
                        fileID = fopen(namef,'w');
                        fprintf(fileID,'%6s %12s\r\n','freq','transfer function');
                        fprintf(fileID,'%6.4f %12.8f\r\n',[transpose(H(hh).x); transpose(H(hh).y)]);
                    end
                case 3
                     for hh=1:nn
                        writematrix([transpose(H(hh).x); transpose(H(hh).y)],filename,'FileType','text','WriteMode','append');
                      end
                case 4
                      oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
                      drawnow;
                     for hh=1:nn
                        writematrix([H(hh).x H(hh).y],filename,'FileType','spreadsheet','WriteMode','append','Sheet',hh);
                     end
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer)             
                otherwise
                    %tdata=table(0,0,0,0);
             end 
            else
                fig = uifigure;
                uialert(fig,'Empty Plot','No Data to Export','Icon','warning');
            end
        end

        % Button pushed function: ExportButton_2
        function ExportButton_phPushed(app, event)
         fig2=app.UIAxes_4.Children;
            nn=numel(fig2);
            if nn>0
            PH(nn)=struct('x',[],'y',[]);
            for hh=1:nn
               x= fig2(hh).XData.'; 
               PH(hh).x=x;
               y= fig2(hh).YData.'; 
               PH(hh).y=y;         
            end
                         [file,path,indx] = uiputfile({'*.mat','MAT-Files (*.mat)';
   '*.txt','Text File (*.txt)';
   '*.csv','Text File (*.csv)';
   '*.xlsx','Worksheets (*.xlsx)'}, ...
   'Select a File');
             filename = fullfile(path,file);
             switch indx
                case 1
                    save(filename,'PH','-mat');
                case 2
                    for hh=1:nn
                        namef=fullfile(path,strcat(file(1:end-4),num2str(hh),'.txt'));
                        fileID = fopen(namef,'w');
                        fprintf(fileID,'%6s %12s\r\n','freq','phase');
                        fprintf(fileID,'%6.4f %12.8f\r\n',[transpose(PH(hh).x); transpose(PH(hh).y)]);
                    end
                case 3
                     for hh=1:nn
                        writematrix([transpose(PH(hh).x); transpose(PH(hh).y)],filename,'FileType','text','WriteMode','append');
                      end
                case 4
                      oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
                      drawnow;
                     for hh=1:nn
                        writematrix([PH(hh).x PH(hh).y],filename,'FileType','spreadsheet','WriteMode','append','Sheet',hh);
                     end
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer)             
                otherwise
                    %tdata=table(0,0,0,0);
             end  
             else
                fig = uifigure;
                uialert(fig,'Empty Plot','No Data to Export','Icon','warning');
            end
        end

        % Button pushed function: ExportButton_3
        function ExportButton_PSDPushed(app, event)
         fig2=app.UIAxes2.Children;
            nn=numel(fig2);
            if nn>0
            PSD(nn)=struct('x',[],'y',[]);
            for hh=1:nn
               x= fig2(hh).XData.'; 
               PSD(hh).x=x;
               y= fig2(hh).YData.'; 
               PSD(hh).y=y;         
            end
                         [file,path,indx] = uiputfile({'*.mat','MAT-Files (*.mat)';
   '*.txt','Text File (*.txt)';
   '*.csv','Text File (*.csv)';
   '*.xlsx','Worksheets (*.xlsx)'}, ...
   'Select a File');
             filename = fullfile(path,file);
             switch indx
                case 1
                    save(filename,'PSD','-mat');
                case 2
                    for hh=1:nn
                        namef=fullfile(path,strcat(file(1:end-4),num2str(hh),'.txt'));
                        fileID = fopen(namef,'w');
                        fprintf(fileID,'%6s %12s\r\n','freq','PSD');
                        fprintf(fileID,'%6.4f %12.8f\r\n',[transpose(PSD(hh).x); transpose(PSD(hh).y)]);
                    end
                case 3
                     for hh=1:nn
                        writematrix([transpose(PSD(hh).x); transpose(PSD(hh).y)],filename,'FileType','text','WriteMode','append');
                      end
                case 4
                      oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
                      drawnow;
                     for hh=1:nn
                        writematrix([PSD(hh).x PSD(hh).y],filename,'FileType','spreadsheet','WriteMode','append','Sheet',hh);
                     end
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer)             
                otherwise
                    %tdata=table(0,0,0,0);
             end  
             else
                fig = uifigure;
                uialert(fig,'Empty Plot','No Data to Export','Icon','warning');
            end
        end

        % Button pushed function: ExportButton_4
        function ExportButton_RSAPushed(app, event)
         fig2=app.UIAxes2_2.Children;
            nn=numel(fig2);
            if nn>0
            RSA(nn)=struct('x',[],'y',[]);
            for hh=1:nn
               x= fig2(hh).XData.'; 
               RSA(hh).x=x;
               y= fig2(hh).YData.'; 
               RSA(hh).y=y;         
            end
                         [file,path,indx] = uiputfile({'*.mat','MAT-Files (*.mat)';
   '*.txt','Text File (*.txt)';
   '*.csv','Text File (*.csv)';
   '*.xlsx','Worksheets (*.xlsx)'}, ...
   'Select a File');
             filename = fullfile(path,file);
             switch indx
                case 1
                    save(filename,'RSA','-mat');
                case 2
                    for hh=1:nn
                        namef=fullfile(path,strcat(file(1:end-4),num2str(hh),'.txt'));
                        fileID = fopen(namef,'w');
                        fprintf(fileID,'%6s %12s\r\n','T','RSA');
                        fprintf(fileID,'%6.4f %12.8f\r\n',[transpose(RSA(hh).x); transpose(RSA(hh).y)]);
                    end
                case 3
                     for hh=1:nn
                        writematrix([transpose(RSA(hh).x); transpose(RSA(hh).y)],filename,'FileType','text','WriteMode','append');
                      end
                case 4
                      oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
                      drawnow;
                     for hh=1:nn
                        writematrix([RSA(hh).x RSA(hh).y],filename,'FileType','spreadsheet','WriteMode','append','Sheet',hh);
                     end
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer)             
                otherwise
                    %tdata=table(0,0,0,0);
             end  
             else
                fig = uifigure;
                uialert(fig,'Empty Plot','No Data to Export','Icon','warning');
            end
        end

        % Button pushed function: ExportButton_5
        function ExportButton_StatsPushed(app, event)
            fig1=app.UIAxes3.Children;
            fig2=app.UIAxes3_2.Children;
            nn=numel(fig2);
            if nn>0
            CDF(nn)=struct('x',[],'y',[]);
            PDF(nn)=struct('x',[],'y',[]);
            for hh=1:nn
               x= fig1(hh).XData.'; 
               CDF(hh).x=x;
               y= fig1(hh).YData.'; 
               CDF(hh).y=y;
               x= fig2(hh).XData.'; 
               PDF(hh).x=x;
               y= fig2(hh).YData.'; 
               PDF(hh).y=y;  
            end
                         [file,path,indx] = uiputfile({'*.mat','MAT-Files (*.mat)';
   '*.txt','Text File (*.txt)';
   '*.csv','Text File (*.csv)';
   '*.xlsx','Worksheets (*.xlsx)'}, ...
   'Select a File');
             filename = fullfile(path,file);
             switch indx
                case 1
                    save(filename,'PDF','CDF','-mat');
                case 2
                    for hh=1:nn
                        namef=fullfile(path,strcat(file(1:end-4),num2str(hh),'.txt'));
                        fileID = fopen(namef,'w');
                        fprintf(fileID,'%6s %12s\r\n','peak acceleration','frequency of non-exceedance');
                        fprintf(fileID,'%6.4f %12.8f\r\n',[transpose(PDF(hh).x); transpose(PDF(hh).y)]);
                        fprintf(fileID,'%6s %12s\r\n','peak acceleration','cumulative density');
                        fprintf(fileID,'%6.4f %12.8f\r\n',[transpose(CDF(hh).x); transpose(CDF(hh).y)]);
                    end
                case 3
                     for hh=1:nn
                        writematrix([transpose(PDF(hh).x); transpose(PDF(hh).y) transpose(CDF(hh).x); transpose(CDF(hh).y)],filename,'FileType','text','WriteMode','append');
                      end
                case 4
                      oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
                      drawnow;
                     for hh=1:nn
                        writematrix([PDF(hh).x PDF(hh).y CDF(hh).x CDF(hh).y],filename,'FileType','spreadsheet','WriteMode','append','Sheet',hh);
                     end
                      set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer)             
                otherwise
                    %tdata=table(0,0,0,0);
             end  
             else
                fig = uifigure;
                uialert(fig,'Empty Plot','No Data to Export','Icon','warning');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if exist('SoilProfile.sra', 'file')==2
                delete('SoilProfile.sra');
            end
            delete(app.SoilProfileDialog)
            if exist('Bedrock.sra', 'file')==2
                delete('Bedrock.sra');
            end
            if exist('solversettings.mat',"file")==2
                delete("solversettings.mat");
            end
            startupFcn(app);
        end

        % Value changed function: ActivateParallelComputingSwitch
        function ActivateParallelComputingSwitchValueChanged(app, event)
            value = app.ActivateParallelComputingSwitch.Value;
            oldpointer = get(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer');
            set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', 'watch')
            drawnow;
            switch value 
                case 'Off'
                    app.flagPar=0;
                    poolobj = gcp('nocreate');
                    if isempty(poolobj)
                     app.ParallelComputingLamp.Color=[1.00,0.00,0.00];  
                    else
                     delete(poolobj);
                     app.ParallelComputingLamp.Color=[1.00,0.00,0.00];        
                    end
                case 'On'
                    app.flagPar=1;
                    p = gcp('nocreate'); % If no pool, do not create new one
                     if isempty(p)
                     parpool("local")
                     app.ParallelComputingLamp.Color=[0.00,1.00,0.00];
                    else
                     app.ParallelComputingLamp.Color=[0.00,1.00,0.00];
                    end
                otherwise
                %do nothing
            end
            set(app.USRAUncertaintySiteResponseAnalysisUIFigure, 'pointer', oldpointer)
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Te=4*sum(app.vz./app.Vs(end-1));
            Ve=sum(app.vz)./sum(app.vz./app.Vs(end-1));
            app.FirstFundamentalFrequencyHzEditField.Value=1/Te;
            app.FirstNaturalPeriodsEditField.Value=Te;
            app.AverageShearVelocityms2EditField.Value=Ve;
            
        end

        % Menu selected function: PSDDefinitionMenu
        function PSDDefinitionMenuSelected(app, event)
            marray=app.PSDarray;
            app.PSDinputDialog = PSDinp(app,marray); 
        end

        % Menu selected function: GenerateTHsMenu
        function GenerateTHsMenuSelected(app, event)
            marray=app.PSDarray;
            app.THGenDialog=GenTH(app,marray);
        end

        % Selection changed function: TypeofanalysisButtonGroup
        function TypeofanalysisButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeofanalysisButtonGroup.SelectedObject;
            switch selectedButton.Text 
                case app.ProbabilityfromPSDButton.Text
                    app.ProbabilityzerocrossingPanel.Enable='on';
                otherwise
                    app.ProbabilityzerocrossingPanel.Enable='off';
            end
        end

        % Selection changed function: SelectPositionButtonGroup
        function SelectPositionButtonGroupSelectionChanged(app, event)
            switch app.AnalysisTypeKnob.Value 
                case 'RVT'
                app.nacc=0;
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            fig = app.USRAUncertaintySiteResponseAnalysisUIFigure;
            selection = uiconfirm(fig,'Are you sure you want to close this window?','Confirm Close'); 
            switch selection 
                case 'OK'
                    delete(app)
                case 'Cancel'
                    return 
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.USRAUncertaintySiteResponseAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {737, 737};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {437, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.USRAUncertaintySiteResponseAnalysisUIFigure = uifigure('Visible', 'off');
            app.USRAUncertaintySiteResponseAnalysisUIFigure.Name = 'USRA - Uncertainty Site Response Analysis';
            app.USRAUncertaintySiteResponseAnalysisUIFigure.Icon = 'USRA-Logo2.png';
            app.USRAUncertaintySiteResponseAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @USRAUncertaintySiteResponseAnalysisUIFigureCloseRequest, true);
            app.USRAUncertaintySiteResponseAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.Example1Menu = uimenu(app.LoadMenu);
            app.Example1Menu.MenuSelectedFcn = createCallbackFcn(app, @Example1MenuSelected, true);
            app.Example1Menu.Text = 'Example 1';
            app.Example2Menu = uimenu(app.LoadMenu);
            app.Example2Menu.MenuSelectedFcn = createCallbackFcn(app, @Example2MenuSelected, true);
            app.Example2Menu.Text = 'Example 2';
            app.Example3Menu = uimenu(app.LoadMenu);
            app.Example3Menu.MenuSelectedFcn = createCallbackFcn(app, @Example3MenuSelected, true);
            app.Example3Menu.Text = 'Example 3';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.StratigraphyMenu = uimenu(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.StratigraphyMenu.Text = 'Stratigraphy';
            app.CyclicParametersMenu = uimenu(app.StratigraphyMenu);
            app.CyclicParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @CyclicParametersMenuSelected, true);
            app.CyclicParametersMenu.Enable = 'off';
            app.CyclicParametersMenu.Text = 'Cyclic Parameters';
            app.SoilProfileMenu = uimenu(app.StratigraphyMenu);
            app.SoilProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @SoilProfileMenuSelected, true);
            app.SoilProfileMenu.Accelerator = 'p';
            app.SoilProfileMenu.Text = 'Soil Profile';
            app.PresaichFormalismMenu = uimenu(app.StratigraphyMenu);
            app.PresaichFormalismMenu.Enable = 'off';
            app.PresaichFormalismMenu.Text = 'Presaich Formalism';
            app.SeismicInputMenu = uimenu(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.SeismicInputMenu.Text = 'Seismic Input';
            app.TimeHistoryFunctionsMenu = uimenu(app.SeismicInputMenu);
            app.TimeHistoryFunctionsMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeHistoryFunctionsMenuSelected, true);
            app.TimeHistoryFunctionsMenu.Accelerator = 'T';
            app.TimeHistoryFunctionsMenu.Text = 'Time History Functions';
            app.PowerSpectralDensityMenu = uimenu(app.SeismicInputMenu);
            app.PowerSpectralDensityMenu.Text = 'Power Spectral Density';
            app.PSDDefinitionMenu = uimenu(app.PowerSpectralDensityMenu);
            app.PSDDefinitionMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDDefinitionMenuSelected, true);
            app.PSDDefinitionMenu.Text = 'PSD Definition';
            app.GenerateTHsMenu = uimenu(app.PowerSpectralDensityMenu);
            app.GenerateTHsMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateTHsMenuSelected, true);
            app.GenerateTHsMenu.Text = 'Generate THs';
            app.SoilUncertaintyMenu = uimenu(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.SoilUncertaintyMenu.Text = 'Soil Uncertainty';
            app.ProbabilityApproachMenu = uimenu(app.SoilUncertaintyMenu);
            app.ProbabilityApproachMenu.Text = 'Probability Approach';
            app.PSDDefinitionMenu_2 = uimenu(app.ProbabilityApproachMenu);
            app.PSDDefinitionMenu_2.Text = 'PSD Definition';
            app.GenerateRandomFieldMenu = uimenu(app.ProbabilityApproachMenu);
            app.GenerateRandomFieldMenu.Text = 'Generate Random Field';
            app.FuzzyApproachMenu = uimenu(app.SoilUncertaintyMenu);
            app.FuzzyApproachMenu.Text = 'Fuzzy Approach';
            app.AdvancedMenu = uimenu(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.AdvancedMenu.Text = 'Advanced';
            app.SignalProcessingMenu = uimenu(app.AdvancedMenu);
            app.SignalProcessingMenu.MenuSelectedFcn = createCallbackFcn(app, @SignalProcessingMenuSelected, true);
            app.SignalProcessingMenu.Text = 'Signal Processing';
            app.SolverSettingsMenu = uimenu(app.AdvancedMenu);
            app.SolverSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SolverSettingsMenuSelected, true);
            app.SolverSettingsMenu.Text = 'Solver Settings';
            app.HelpMenu = uimenu(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.ReferencesMenu = uimenu(app.HelpMenu);
            app.ReferencesMenu.Text = 'References';
            app.GridLayout = uigridlayout(app.USRAUncertaintySiteResponseAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Pre-Processing';
            app.ActivateAnalysisPanel = uipanel(app.LeftPanel);
            app.ActivateAnalysisPanel.Title = 'Activate Analysis';
            app.ActivateParallelComputingSwitchLabel = uilabel(app.ActivateAnalysisPanel);
            app.ActivateParallelComputingSwitchLabel.Text = 'Activate Parallel Computing';
            app.ActivateParallelComputingSwitch = uiswitch(app.ActivateAnalysisPanel, 'rocker');
            app.ActivateParallelComputingSwitch.ValueChangedFcn = createCallbackFcn(app, @ActivateParallelComputingSwitchValueChanged, true);
            app.AnalysisTypeKnobLabel = uilabel(app.ActivateAnalysisPanel);
            app.AnalysisTypeKnobLabel.Text = 'Analysis Type';
            app.AnalysisTypeKnob = uiknob(app.ActivateAnalysisPanel, 'discrete');
            app.AnalysisTypeKnob.Items = {'Off', 'Deterministic', 'MCS', 'RVT', 'Fuzzy+RVT'};
            app.AnalysisTypeKnob.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeKnobValueChanged, true);
            app.AnalysisOptionsPanel = uipanel(app.LeftPanel);
            app.AnalysisOptionsPanel.Title = 'Analysis Options';
            app.WavePropagationSwitchLabel = uilabel(app.AnalysisOptionsPanel);
            app.WavePropagationSwitchLabel.Text = 'Wave Propagation';
            app.WavePropagationSwitch = uiswitch(app.AnalysisOptionsPanel, 'slider');
            app.WavePropagationSwitch.Items = {'Upwards', 'Downwards'};
            app.WavePropagationSwitch.ValueChangedFcn = createCallbackFcn(app, @WavePropagationSwitchValueChanged, true);
            app.WavePropagationSwitch.Value = 'Upwards';
            app.SoilPropertiesSwitchLabel = uilabel(app.AnalysisOptionsPanel);
            app.SoilPropertiesSwitchLabel.Text = 'Soil Properties';
            app.SoilPropertiesSwitch = uiswitch(app.AnalysisOptionsPanel, 'slider');
            app.SoilPropertiesSwitch.Items = {'Linear', 'Equivalent Linear'};
            app.SoilPropertiesSwitch.ValueChangedFcn = createCallbackFcn(app, @SoilPropertiesSwitchValueChanged, true);
            app.SoilPropertiesSwitch.Value = 'Linear';
            app.ChecksPanel = uipanel(app.LeftPanel);
            app.ChecksPanel.Title = 'Checks';
            app.StratigrafyLampLabel = uilabel(app.ChecksPanel);
            app.StratigrafyLampLabel.Text = 'Stratigrafy';
            app.StratigrafyLamp = uilamp(app.ChecksPanel);
            app.SeismicInputLampLabel = uilabel(app.ChecksPanel);
            app.SeismicInputLampLabel.Text = 'Seismic Input';
            app.SeismicInputLamp = uilamp(app.ChecksPanel);
            app.StochasticInputLampLabel = uilabel(app.ChecksPanel);
            app.StochasticInputLampLabel.Text = 'Stochastic Input';
            app.StochasticInputLamp = uilamp(app.ChecksPanel);
            app.RunButton = uibutton(app.ChecksPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CheckButton = uibutton(app.ChecksPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Tooltip = {'Check that every data input has been set up correctly before activating "Run"'};
            app.CheckButton.Text = 'Check';
            app.ResetButton = uibutton(app.ChecksPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ParallelComputingLampLabel = uilabel(app.ChecksPanel);
            app.ParallelComputingLampLabel.Text = 'Parallel Computing';
            app.ParallelComputingLamp = uilamp(app.ChecksPanel);
            app.SoilUncertaintyLampLabel = uilabel(app.ChecksPanel);
            app.SoilUncertaintyLampLabel.Text = 'Soil Uncertainty';
            app.SoilUncertaintyLamp = uilamp(app.ChecksPanel);
            app.UncertaintySiteResponseAnalysisUSRALabel = uilabel(app.LeftPanel);
            app.UncertaintySiteResponseAnalysisUSRALabel.Text = 'Uncertainty Site Response Analysis (USRA)';
            app.InputPositionSwitch_2Label = uilabel(app.LeftPanel);
            app.InputPositionSwitch_2Label.Text = 'Input Position';
            app.InputPositionSwitch_2 = uiswitch(app.LeftPanel, 'slider');
            app.InputPositionSwitch_2.Items = {'Outcrop', 'Bedrock'};
            app.InputPositionSwitch_2.ValueChangedFcn = createCallbackFcn(app, @InputPositionSwitch_2ValueChanged, true);
            app.InputPositionSwitch_2.Value = 'Outcrop';
            app.SourceofUncertaintyPanel = uipanel(app.LeftPanel);
            app.SourceofUncertaintyPanel.Title = 'Source of Uncertainty';
            app.SeismicInputButton = uibutton(app.SourceofUncertaintyPanel, 'state');
            app.SeismicInputButton.Text = 'Seismic Input';
            app.SoilPropertiesButton = uibutton(app.SourceofUncertaintyPanel, 'state');
            app.SoilPropertiesButton.Text = 'Soil Properties';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'USRA-Logo2.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Enable = 'off';
            app.RightPanel.Title = 'Post-Processing';
            app.SelectPositionButtonGroup = uibuttongroup(app.RightPanel);
            app.SelectPositionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectPositionButtonGroupSelectionChanged, true);
            app.SelectPositionButtonGroup.Title = 'Select Position';
            app.SurfaceButton = uiradiobutton(app.SelectPositionButtonGroup);
            app.SurfaceButton.Text = 'Surface';
            app.SurfaceButton.Value = true;
            app.BedrockButton = uiradiobutton(app.SelectPositionButtonGroup);
            app.BedrockButton.Text = 'Bedrock';
            app.InsideButton = uiradiobutton(app.SelectPositionButtonGroup);
            app.InsideButton.Text = 'Inside';
            app.DepthmEditFieldLabel = uilabel(app.SelectPositionButtonGroup);
            app.DepthmEditFieldLabel.Text = 'Depth [m]';
            app.DepthmEditField = uieditfield(app.SelectPositionButtonGroup, 'numeric');
            app.DepthmEditField.Limits = [0 Inf];
            app.DepthmEditField.ValueChangedFcn = createCallbackFcn(app, @DepthmEditFieldValueChanged, true);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.THTab = uitab(app.TabGroup);
            app.THTab.Title = 'TH';
            app.UIAxes = uiaxes(app.THTab);
            title(app.UIAxes, 'Time History')
            xlabel(app.UIAxes, 't [sec]')
            ylabel(app.UIAxes, 'acc [g]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.THTab);
            title(app.UIAxes_2, 'FFT')
            xlabel(app.UIAxes_2, 'Freq [Hz]')
            ylabel(app.UIAxes_2, '|fft(acc)| ')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ShowButton = uibutton(app.THTab, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ClearButton = uibutton(app.THTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.HoldButton = uibutton(app.THTab, 'state');
            app.HoldButton.Text = 'Hold';
            app.ExportButton = uibutton(app.THTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushedTH, true);
            app.ExportButton.Icon = 'ExportIcon.png';
            app.ExportButton.Text = 'Export';
            app.ExportButton_6 = uibutton(app.THTab, 'push');
            app.ExportButton_6.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_FFTPushed, true);
            app.ExportButton_6.Icon = 'ExportIcon.png';
            app.ExportButton_6.Text = 'Export';
            app.TransferFunctionTab = uitab(app.TabGroup);
            app.TransferFunctionTab.Title = 'Transfer Function';
            app.UIAxes_3 = uiaxes(app.TransferFunctionTab);
            title(app.UIAxes_3, 'TF')
            xlabel(app.UIAxes_3, 'freq [Hz]')
            ylabel(app.UIAxes_3, '|H| [-]')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.TransferFunctionTab);
            title(app.UIAxes_4, 'TF')
            xlabel(app.UIAxes_4, 'freq [Hz]')
            ylabel(app.UIAxes_4, 'Phase [deg]')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.YLim = [-180 180];
            app.UIAxes_4.YTick = [-180 -90 0 90 180];
            app.UIAxes_4.YTickLabel = {'-180'; '-90'; '0'; '90'; '180'};
            app.ShowButton_TF = uibutton(app.TransferFunctionTab, 'push');
            app.ShowButton_TF.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_TFPushed, true);
            app.ShowButton_TF.Text = 'Show';
            app.ClearButton_4 = uibutton(app.TransferFunctionTab, 'push');
            app.ClearButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_4Pushed, true);
            app.ClearButton_4.Text = 'Clear';
            app.HoldButton_3 = uibutton(app.TransferFunctionTab, 'state');
            app.HoldButton_3.Text = 'Hold';
            app.ExportButton_2 = uibutton(app.TransferFunctionTab, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_phPushed, true);
            app.ExportButton_2.Icon = 'ExportIcon.png';
            app.ExportButton_2.Text = 'Export';
            app.ExportButton_7 = uibutton(app.TransferFunctionTab, 'push');
            app.ExportButton_7.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_HPushed, true);
            app.ExportButton_7.Icon = 'ExportIcon.png';
            app.ExportButton_7.Text = 'Export';
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.Title = 'PSD';
            app.UIAxes2 = uiaxes(app.PSDTab);
            title(app.UIAxes2, 'PSD')
            xlabel(app.UIAxes2, 'freq [Hz]')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ShowButton_PSD = uibutton(app.PSDTab, 'push');
            app.ShowButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_PSDPushed, true);
            app.ShowButton_PSD.Text = 'Show';
            app.ClearButton_2 = uibutton(app.PSDTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.HoldButton_2 = uibutton(app.PSDTab, 'state');
            app.HoldButton_2.Text = 'Hold';
            app.ZoomLabel = uilabel(app.PSDTab);
            app.ZoomLabel.Text = 'Zoom [%]';
            app.ZoomSlider = uislider(app.PSDTab);
            app.ZoomSlider.Limits = [0 99];
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ExportButton_3 = uibutton(app.PSDTab, 'push');
            app.ExportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_PSDPushed, true);
            app.ExportButton_3.Icon = 'ExportIcon.png';
            app.ExportButton_3.Text = 'Export';
            app.ResponseSpectrumTab = uitab(app.TabGroup);
            app.ResponseSpectrumTab.Title = 'Response Spectrum';
            app.UIAxes2_2 = uiaxes(app.ResponseSpectrumTab);
            title(app.UIAxes2_2, 'RSA')
            xlabel(app.UIAxes2_2, 'T [s]')
            ylabel(app.UIAxes2_2, 'Sa [g]')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XLim = [0 4];
            app.ShowButton_RSA = uibutton(app.ResponseSpectrumTab, 'push');
            app.ShowButton_RSA.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_RSAPushed, true);
            app.ShowButton_RSA.Text = 'Show';
            app.ClearButton_3 = uibutton(app.ResponseSpectrumTab, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_3Pushed, true);
            app.ClearButton_3.Text = 'Clear';
            app.HoldButton_4 = uibutton(app.ResponseSpectrumTab, 'state');
            app.HoldButton_4.Text = 'Hold';
            app.ComparewithDropDownLabel = uilabel(app.ResponseSpectrumTab);
            app.ComparewithDropDownLabel.Text = 'Compare with';
            app.ComparewithDropDown = uidropdown(app.ResponseSpectrumTab);
            app.ComparewithDropDown.Items = {'EN1998 GrType A', 'EN1998 GrType B', 'EN1998 GrType C', 'EN1998 GrType D'};
            app.ComparewithDropDown.Value = 'EN1998 GrType A';
            app.PlotButton = uibutton(app.ResponseSpectrumTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.aggEditFieldLabel = uilabel(app.ResponseSpectrumTab);
            app.aggEditFieldLabel.Text = 'ag [g]';
            app.aggEditField = uieditfield(app.ResponseSpectrumTab, 'numeric');
            app.aggEditField.Limits = [0 Inf];
            app.aggEditField.Value = 0.2;
            app.PlotResponseSpectrumPanel = uipanel(app.ResponseSpectrumTab);
            app.PlotResponseSpectrumPanel.Title = 'Plot Response Spectrum';
            app.IndividualButton = uibutton(app.PlotResponseSpectrumPanel, 'state');
            app.IndividualButton.Text = 'Individual';
            app.IndividualButton.Value = true;
            app.AverageButton = uibutton(app.PlotResponseSpectrumPanel, 'state');
            app.AverageButton.Text = 'Average';
            app.ExportButton_4 = uibutton(app.ResponseSpectrumTab, 'push');
            app.ExportButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_RSAPushed, true);
            app.ExportButton_4.Icon = 'ExportIcon.png';
            app.ExportButton_4.Text = 'Export';
            app.AnalysisTypePanel = uipanel(app.ResponseSpectrumTab);
            app.AnalysisTypePanel.Title = 'Analysis Type';
            app.DeterministicfromTHsButton = uibutton(app.AnalysisTypePanel, 'state');
            app.DeterministicfromTHsButton.Text = 'Deterministic (from THs)';
            app.DeterministicfromTHsButton.Value = true;
            app.StochasticfromPSDButton = uibutton(app.AnalysisTypePanel, 'state');
            app.StochasticfromPSDButton.Enable = 'off';
            app.StochasticfromPSDButton.Text = 'Stochastic (from PSD)';
            app.SoilDepositDataTab = uitab(app.TabGroup);
            app.SoilDepositDataTab.Title = 'Soil Deposit Data';
            app.LinearPropertiesPanel = uipanel(app.SoilDepositDataTab);
            app.LinearPropertiesPanel.Title = 'Linear Properties';
            app.FirstNaturalPeriodsEditFieldLabel = uilabel(app.LinearPropertiesPanel);
            app.FirstNaturalPeriodsEditFieldLabel.Text = 'First Natural Period [s]';
            app.FirstNaturalPeriodsEditField = uieditfield(app.LinearPropertiesPanel, 'numeric');
            app.FirstNaturalPeriodsEditField.Limits = [0 Inf];
            app.FirstNaturalPeriodsEditField.Editable = 'off';
            app.FirstFundamentalFrequencyHzEditFieldLabel = uilabel(app.LinearPropertiesPanel);
            app.FirstFundamentalFrequencyHzEditFieldLabel.Text = 'First Fundamental Frequency [Hz]';
            app.FirstFundamentalFrequencyHzEditField = uieditfield(app.LinearPropertiesPanel, 'numeric');
            app.FirstFundamentalFrequencyHzEditField.Limits = [0 Inf];
            app.FirstFundamentalFrequencyHzEditField.Editable = 'off';
            app.FrequencyatthepeakoftheTransferFunctionHzEditFieldLabel = uilabel(app.LinearPropertiesPanel);
            app.FrequencyatthepeakoftheTransferFunctionHzEditFieldLabel.Text = 'Frequency at the peak of the Transfer Function [Hz[';
            app.FrequencyatthepeakoftheTransferFunctionHzEditField = uieditfield(app.LinearPropertiesPanel, 'numeric');
            app.FrequencyatthepeakoftheTransferFunctionHzEditField.Limits = [0 Inf];
            app.FrequencyatthepeakoftheTransferFunctionHzEditField.Editable = 'off';
            app.AverageShearVelocityms2EditFieldLabel = uilabel(app.LinearPropertiesPanel);
            app.AverageShearVelocityms2EditFieldLabel.Text = 'Average Shear Velocity [ms^-2]';
            app.AverageShearVelocityms2EditField = uieditfield(app.LinearPropertiesPanel, 'numeric');
            app.AverageShearVelocityms2EditField.Limits = [0 Inf];
            app.AverageShearVelocityms2EditField.Editable = 'off';
            app.EquivalentLinearPropertiesPanel = uipanel(app.SoilDepositDataTab);
            app.EquivalentLinearPropertiesPanel.Title = 'Equivalent Linear Properties';
            app.FirstNaturalPeriodsEditField_2Label = uilabel(app.EquivalentLinearPropertiesPanel);
            app.FirstNaturalPeriodsEditField_2Label.Text = 'First Natural Period [s]';
            app.FirstNaturalPeriodsEditField_2 = uieditfield(app.EquivalentLinearPropertiesPanel, 'numeric');
            app.FirstNaturalPeriodsEditField_2.Limits = [0 Inf];
            app.FirstNaturalPeriodsEditField_2.Editable = 'off';
            app.FirstFundamentalFrequencyHzEditField_2Label = uilabel(app.EquivalentLinearPropertiesPanel);
            app.FirstFundamentalFrequencyHzEditField_2Label.Text = 'First Fundamental Frequency [Hz]';
            app.FirstFundamentalFrequencyHzEditField_2 = uieditfield(app.EquivalentLinearPropertiesPanel, 'numeric');
            app.FirstFundamentalFrequencyHzEditField_2.Limits = [0 Inf];
            app.FirstFundamentalFrequencyHzEditField_2.Editable = 'off';
            app.FrequencyatthepeakoftheTransferFunctionHzEditField_2Label = uilabel(app.EquivalentLinearPropertiesPanel);
            app.FrequencyatthepeakoftheTransferFunctionHzEditField_2Label.Text = 'Frequency at the peak of the Transfer Function [Hz[';
            app.FrequencyatthepeakoftheTransferFunctionHzEditField_2 = uieditfield(app.EquivalentLinearPropertiesPanel, 'numeric');
            app.FrequencyatthepeakoftheTransferFunctionHzEditField_2.Limits = [0 Inf];
            app.FrequencyatthepeakoftheTransferFunctionHzEditField_2.Editable = 'off';
            app.AverageShearVelocityms2EditField_2Label = uilabel(app.EquivalentLinearPropertiesPanel);
            app.AverageShearVelocityms2EditField_2Label.Text = 'Average Shear Velocity [ms^-2]';
            app.AverageShearVelocityms2EditField_2 = uieditfield(app.EquivalentLinearPropertiesPanel, 'numeric');
            app.AverageShearVelocityms2EditField_2.Limits = [0 Inf];
            app.AverageShearVelocityms2EditField_2.Editable = 'off';
            app.SoilDepositSpinnerLabel = uilabel(app.SoilDepositDataTab);
            app.SoilDepositSpinnerLabel.Text = 'Soil Deposit #';
            app.SoilDepositSpinner = uispinner(app.SoilDepositDataTab);
            app.SoilDepositSpinner.Limits = [1 Inf];
            app.SoilDepositSpinner.Value = 1;
            app.CalculateButton = uibutton(app.SoilDepositDataTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DataDistributionTab = uitab(app.TabGroup);
            app.DataDistributionTab.Title = 'Data Distribution';
            app.UIAxes3 = uiaxes(app.DataDistributionTab);
            title(app.UIAxes3, 'CDF')
            xlabel(app.UIAxes3, 'peak acceleration [g]')
            ylabel(app.UIAxes3, 'Cumulative density')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.DataDistributionTab);
            title(app.UIAxes3_2, 'PDF')
            xlabel(app.UIAxes3_2, 'peak acceleration [g]')
            ylabel(app.UIAxes3_2, 'frequency of non-exceedance')
            zlabel(app.UIAxes3_2, 'Z')
            app.TypeofanalysisButtonGroup = uibuttongroup(app.DataDistributionTab);
            app.TypeofanalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofanalysisButtonGroupSelectionChanged, true);
            app.TypeofanalysisButtonGroup.Title = 'Type of analysis';
            app.StatisticalfromTHButton = uiradiobutton(app.TypeofanalysisButtonGroup);
            app.StatisticalfromTHButton.Text = 'Statistical (from TH)';
            app.ProbabilityfromPSDButton = uiradiobutton(app.TypeofanalysisButtonGroup);
            app.ProbabilityfromPSDButton.Text = 'Probability (from PSD)';
            app.ProbabilityfromPSDButton.Value = true;
            app.ShowButton_5 = uibutton(app.DataDistributionTab, 'push');
            app.ShowButton_5.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_5Pushed, true);
            app.ShowButton_5.Text = 'Show';
            app.ClearButton_5 = uibutton(app.DataDistributionTab, 'push');
            app.ClearButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_5Pushed, true);
            app.ClearButton_5.Text = 'Clear';
            app.HoldButton_5 = uibutton(app.DataDistributionTab, 'state');
            app.HoldButton_5.Text = 'Hold';
            app.ProbabilityzerocrossingPanel = uipanel(app.DataDistributionTab);
            app.ProbabilityzerocrossingPanel.Title = 'Probability (zero crossing)';
            app.StationarytimewindowsEditFieldLabel = uilabel(app.ProbabilityzerocrossingPanel);
            app.StationarytimewindowsEditFieldLabel.Text = 'Stationary time window [s]';
            app.StationarytimewindowsEditField = uieditfield(app.ProbabilityzerocrossingPanel, 'numeric');
            app.StationarytimewindowsEditField.Limits = [0 Inf];
            app.ZerothorderspectralmomentEditFieldLabel = uilabel(app.ProbabilityzerocrossingPanel);
            app.ZerothorderspectralmomentEditFieldLabel.Text = 'Zeroth-order spectral moment ';
            app.ZerothorderspectralmomentEditField = uieditfield(app.ProbabilityzerocrossingPanel, 'numeric');
            app.ZerothorderspectralmomentEditField.Editable = 'off';
            app.MedianvalueofthepeakaccelerationEditFieldLabel = uilabel(app.ProbabilityzerocrossingPanel);
            app.MedianvalueofthepeakaccelerationEditFieldLabel.Text = 'Median value of the peak acceleration ';
            app.MedianvalueofthepeakaccelerationEditField = uieditfield(app.ProbabilityzerocrossingPanel, 'numeric');
            app.MedianvalueofthepeakaccelerationEditField.Editable = 'off';
            app.ExportButton_5 = uibutton(app.DataDistributionTab, 'push');
            app.ExportButton_5.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_StatsPushed, true);
            app.ExportButton_5.Icon = 'ExportIcon.png';
            app.ExportButton_5.Text = 'Export';
            app.SelectTimeHistorySamplesButtonGroup = uibuttongroup(app.RightPanel);
            app.SelectTimeHistorySamplesButtonGroup.Title = 'Select Time History Samples';
            app.AllButton = uiradiobutton(app.SelectTimeHistorySamplesButtonGroup);
            app.AllButton.Text = 'All';
            app.RangeButton = uiradiobutton(app.SelectTimeHistorySamplesButtonGroup);
            app.RangeButton.Text = 'Range';
            app.SingleButton = uiradiobutton(app.SelectTimeHistorySamplesButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.accSpinnerLabel = uilabel(app.SelectTimeHistorySamplesButtonGroup);
            app.accSpinnerLabel.Text = '#acc';
            app.accSpinner = uispinner(app.SelectTimeHistorySamplesButtonGroup);
            app.accSpinner.Limits = [1 Inf];
            app.accSpinner.Value = 1;
            app.fromaccSpinnerLabel = uilabel(app.SelectTimeHistorySamplesButtonGroup);
            app.fromaccSpinnerLabel.Text = 'from #acc';
            app.fromaccSpinner = uispinner(app.SelectTimeHistorySamplesButtonGroup);
            app.fromaccSpinner.Limits = [1 Inf];
            app.fromaccSpinner.Value = 1;
            app.toaccSpinnerLabel = uilabel(app.SelectTimeHistorySamplesButtonGroup);
            app.toaccSpinnerLabel.Text = 'to #acc';
            app.toaccSpinner = uispinner(app.SelectTimeHistorySamplesButtonGroup);
            app.toaccSpinner.Limits = [1 Inf];
            app.toaccSpinner.Value = 1;
            app.SelectSoilDepositSamplesButtonGroup = uibuttongroup(app.RightPanel);
            app.SelectSoilDepositSamplesButtonGroup.Enable = 'off';
            app.SelectSoilDepositSamplesButtonGroup.Title = 'Select Soil Deposit Samples';
            app.AllButton_2 = uiradiobutton(app.SelectSoilDepositSamplesButtonGroup);
            app.AllButton_2.Text = 'All';
            app.AllButton_2.Value = true;
            app.RangeButton_2 = uiradiobutton(app.SelectSoilDepositSamplesButtonGroup);
            app.RangeButton_2.Text = 'Range';
            app.SingleButton_2 = uiradiobutton(app.SelectSoilDepositSamplesButtonGroup);
            app.SingleButton_2.Text = 'Single';
            app.soilSpinnerLabel = uilabel(app.SelectSoilDepositSamplesButtonGroup);
            app.soilSpinnerLabel.Text = '#soil';
            app.soilSpinner = uispinner(app.SelectSoilDepositSamplesButtonGroup);
            app.soilSpinner.Limits = [1 Inf];
            app.soilSpinner.Value = 1;
            app.fromsoilSpinnerLabel = uilabel(app.SelectSoilDepositSamplesButtonGroup);
            app.fromsoilSpinnerLabel.Text = 'from #soil';
            app.fromsoilSpinner = uispinner(app.SelectSoilDepositSamplesButtonGroup);
            app.fromsoilSpinner.Limits = [1 Inf];
            app.fromsoilSpinner.Value = 1;
            app.tosoilSpinnerLabel = uilabel(app.SelectSoilDepositSamplesButtonGroup);
            app.tosoilSpinnerLabel.Text = 'to #soil';
            app.tosoilSpinner = uispinner(app.SelectSoilDepositSamplesButtonGroup);
            app.tosoilSpinner.Limits = [1 Inf];
            app.tosoilSpinner.Value = 1;
            % Show the figure after all components are created
            app.USRAUncertaintySiteResponseAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainSRA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.USRAUncertaintySiteResponseAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.USRAUncertaintySiteResponseAnalysisUIFigure)
        end
    end
end